classdef Spectral_Power_Estimate_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectralDensityWindowUIFigure   matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        ExportDataasMenu                matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        ChangeStandardSaveLocationMenu  matlab.ui.container.Menu
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        PowerEstimateWindowLabel        matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ChannelSelectionPreprocessedChannelEditField  matlab.ui.control.EditField
        ChannelSelectionPreprocessedChannelEditFieldLabel  matlab.ui.control.Label
        LockYLimCheckBox                matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu3                    matlab.ui.container.ContextMenu
        ChangeTitleMenu                 matlab.ui.container.Menu
        ChangeXLabelMenu                matlab.ui.container.Menu
        ChangeYLabelMenu                matlab.ui.container.Menu
        ChangeXlimMenu                  matlab.ui.container.Menu
        ChangeYlimMenu                  matlab.ui.container.Menu
        ChangeLegendMenu                matlab.ui.container.Menu
        ChangeFontsizeMenu              matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ExistflagSDE = [];
        PDLim = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SpectralDensityWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            app.Mainapp.SpectralEstApp = app;

            if ~isfield(app.Mainapp.Data,'Preprocessed') || ~isfield(app.Mainapp.Data.Info,'FilterMethod')
                f = msgbox("Error: No Preprocessed data found or data was not low pass filtered. Exiting");
                app.Mainapp.CSDApp = [];
                delete(app);
                return;
            end
            if isfield(app.Mainapp.Data.Info,'FilterMethod')
                if ~strcmp(app.Mainapp.Data.Info.FilterMethod,"Low-Pass")
                    f = msgbox("Error: No low pass filtered data found. Exiting");
                    app.Mainapp.CSDApp = [];
                    delete(app);
                    return;
                end
            end

            app.ExistflagSDE = 1;

           app.ChannelSelectionPreprocessedChannelEditField.Value = strcat(num2str(1),",",num2str(size(app.Mainapp.Data.Preprocessed,1)));

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
           
            CommaIndex = strfind(app.ChannelSelectionPreprocessedChannelEditField.Value,",");
            ChannelSelection(1) = str2double(app.ChannelSelectionPreprocessedChannelEditField.Value(1:CommaIndex-1));
            ChannelSelection(2) = str2double(app.ChannelSelectionPreprocessedChannelEditField.Value(CommaIndex+1:end));

            xtickangle(app.UIAxes, 45);
            
            app.Mainapp.SpectralEstApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
                      
        end

        % Close request function: SpectralDensityWindowUIFigure
        function SpectralDensityWindowUIFigureCloseRequest(app, event)
            app.Mainapp.SpectralEstApp = [];
            app.ExistflagSDE = [];
            delete(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: 
        % ChannelSelectionPreprocessedChannelEditField
        function ChannelSelectionPreprocessedChannelEditFieldValueChanged(app, event)
            if isfield(app.Mainapp.Data,"Preprocessed")
                [app.ChannelSelectionPreprocessedChannelEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionPreprocessedChannelEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.Preprocessed,1))),1,0);
           else
                [app.ChannelSelectionPreprocessedChannelEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionPreprocessedChannelEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.Raw,1))),1,0);
           end
            app.Mainapp.SpectralEstApp = app;
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,0,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);
        end

        % Value changed function: LockYLimCheckBox
        function LockYLimCheckBoxValueChanged(app, event)
            value = app.LockYLimCheckBox.Value;
            
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.SpectralDensityWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.SpectralDensityWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: ChangeTitleMenu
        function ChangeTitleMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'title');
        end

        % Menu selected function: ChangeXLabelMenu
        function ChangeXLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu
        function ChangeYLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu
        function ChangeXlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlim');
        end

        % Menu selected function: ChangeYlimMenu
        function ChangeYlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylim');
        end

        % Menu selected function: ChangeLegendMenu
        function ChangeLegendMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu
        function ChangeFontsizeMenuSelected(app, event)
            
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spectral_Power_Estimate_Main_Window");
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spectral_Power_Estimate_Main_Window");
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = str2double(app.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.Mainapp.CurrentTimePoints/app.Mainapp.Data.Info.NativeSamplingRate;
            end
            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Live_Spectral_Power_Estimate_Main_Window");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectralDensityWindowUIFigure = uifigure('Visible', 'off');
            app.SpectralDensityWindowUIFigure.Name = 'Spectral Density Window';
            app.SpectralDensityWindowUIFigure.Icon = 'Logo.png';
            app.SpectralDensityWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpectralDensityWindowUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.SpectralDensityWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.ExportDataasMenu = uimenu(app.SpectralDensityWindowUIFigure);
            app.ExportDataasMenu.Text = '   Export Data as   ';
            app.matMenu = uimenu(app.ExportDataasMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataasMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ExportDataasMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.ChangeStandardSaveLocationMenu = uimenu(app.ExportDataasMenu);
            app.ChangeStandardSaveLocationMenu.Text = 'Change Standard Save Location';
            app.UIAxes = uiaxes(app.SpectralDensityWindowUIFigure);
            app.Panel = uipanel(app.SpectralDensityWindowUIFigure);
            app.LockYLimCheckBox = uicheckbox(app.Panel);
            app.LockYLimCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockYLimCheckBoxValueChanged, true);
            app.LockYLimCheckBox.Text = '   Lock YLim';
            app.LockYLimCheckBox.Value = true;
            app.ChannelSelectionPreprocessedChannelEditFieldLabel = uilabel(app.Panel);
            app.ChannelSelectionPreprocessedChannelEditFieldLabel.Text = {'Channel Selection'; '(Preprocessed Channel)'};
            app.ChannelSelectionPreprocessedChannelEditField = uieditfield(app.Panel, 'text');
            app.ChannelSelectionPreprocessedChannelEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionPreprocessedChannelEditFieldValueChanged, true);
            app.PowerEstimateWindowLabel = uilabel(app.SpectralDensityWindowUIFigure);
            app.PowerEstimateWindowLabel.Text = 'Power Estimate Window';
            app.Image = uiimage(app.SpectralDensityWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.SpectralDensityWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.SpectralDensityWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ContextMenu3 = uicontextmenu(app.SpectralDensityWindowUIFigure);
            app.ChangeTitleMenu = uimenu(app.ContextMenu3);
            app.ChangeTitleMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenuSelected, true);
            app.ChangeTitleMenu.Text = 'Change Title';
            app.ChangeXLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeXLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenuSelected, true);
            app.ChangeXLabelMenu.Text = 'Change X Label';
            app.ChangeYLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeYLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenuSelected, true);
            app.ChangeYLabelMenu.Text = 'Change Y Label';
            app.ChangeXlimMenu = uimenu(app.ContextMenu3);
            app.ChangeXlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenuSelected, true);
            app.ChangeXlimMenu.Text = 'Change Xlim';
            app.ChangeYlimMenu = uimenu(app.ContextMenu3);
            app.ChangeYlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenuSelected, true);
            app.ChangeYlimMenu.Text = 'Change Ylim';
            app.ChangeLegendMenu = uimenu(app.ContextMenu3);
            app.ChangeLegendMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenuSelected, true);
            app.ChangeLegendMenu.Text = 'Change Legend';
            app.ChangeFontsizeMenu = uimenu(app.ContextMenu3);
            app.ChangeFontsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenuSelected, true);
            app.ChangeFontsizeMenu.Text = 'Change Fontsize';
            % Assign app.ContextMenu3
            app.UIAxes.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.SpectralDensityWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spectral_Power_Estimate_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpectralDensityWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpectralDensityWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectralDensityWindowUIFigure)
        end
    end
end