classdef Cut_Recording_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Cut_Recording_TimeUIFigure    matlab.ui.Figure
        CloseButton                   matlab.ui.control.Button
        SaveselectionandaddtopipelineButton  matlab.ui.control.Button
        CutRecordingEndCheckBox       matlab.ui.control.CheckBox
        CutRecordingStartCheckBox     matlab.ui.control.CheckBox
        TimetocutbeforeendsEditField  matlab.ui.control.EditField
        TimetocutbeforeendsEditFieldLabel  matlab.ui.control.Label
        TimetocutfromstartsEditField  matlab.ui.control.EditField
        TimetocutLabel                matlab.ui.control.Label
    end

    
    properties (Access = public)
        PreprocessingApp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PreprocessingApp)
            movegui(app.Cut_Recording_TimeUIFigure, 'center');

            app.PreprocessingApp = PreprocessingApp;

        end

        % Button pushed function: SaveselectionandaddtopipelineButton
        function SaveselectionandaddtopipelineButtonPushed(app, event)
            if app.CutRecordingStartCheckBox.Value == 1
                app.PreprocessingApp.Info.CutStart = str2double(app.TimetocutfromstartsEditField.Value);
                [app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,texttoshow] = Preprocess_Module_Construct_Pipeline("CutStart",app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,0,[],[],[],[],[],[],app.PreprocessingApp.Mainapp.Data.Info.NativeSamplingRate);
                
            end
            if app.CutRecordingEndCheckBox.Value == 1
                app.PreprocessingApp.Info.CutEnd = str2double(app.TimetocutbeforeendsEditField.Value);
                [app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,texttoshow] = Preprocess_Module_Construct_Pipeline("CutEnd",app.PreprocessingApp.Info,app.PreprocessingApp.PreprocessingSteps,0,[],[],[],[],[],[],app.PreprocessingApp.Mainapp.Data.Info.NativeSamplingRate);
                
            end

            if ~isempty(texttoshow)
                app.PreprocessingApp.TextArea.Value = texttoshow;
            end

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Cut_Recording_TimeUIFigure = uifigure('Visible', 'off');
            app.Cut_Recording_TimeUIFigure.Name = 'Cut_Recording_Time';
            app.Cut_Recording_TimeUIFigure.Icon = 'Logo.png';
            app.TimetocutLabel = uilabel(app.Cut_Recording_TimeUIFigure);
            app.TimetocutLabel.Text = {'Time to cut'; 'from start [s]'};
            app.TimetocutfromstartsEditField = uieditfield(app.Cut_Recording_TimeUIFigure, 'text');
            app.TimetocutbeforeendsEditFieldLabel = uilabel(app.Cut_Recording_TimeUIFigure);
            app.TimetocutbeforeendsEditFieldLabel.Text = {'Time to cut '; 'before end [s]'};
            app.TimetocutbeforeendsEditField = uieditfield(app.Cut_Recording_TimeUIFigure, 'text');
            app.CutRecordingStartCheckBox = uicheckbox(app.Cut_Recording_TimeUIFigure);
            app.CutRecordingStartCheckBox.Text = '  Cut Recording Start';
            app.CutRecordingEndCheckBox = uicheckbox(app.Cut_Recording_TimeUIFigure);
            app.CutRecordingEndCheckBox.Text = '  Cut Recording End';
            app.SaveselectionandaddtopipelineButton = uibutton(app.Cut_Recording_TimeUIFigure, 'push');
            app.SaveselectionandaddtopipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SaveselectionandaddtopipelineButtonPushed, true);
            app.SaveselectionandaddtopipelineButton.Text = {'Save selection and '; 'add to pipeline'};
            app.CloseButton = uibutton(app.Cut_Recording_TimeUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.Cut_Recording_TimeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cut_Recording_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Cut_Recording_TimeUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Cut_Recording_TimeUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Cut_Recording_TimeUIFigure)
        end
    end
end