classdef CSD_Analysis_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CSDAnalysisUIFigure             matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        Show2DimensionalPlotsMenu       matlab.ui.container.Menu
        Show3DimensionalPlotsMenu       matlab.ui.container.Menu
        ChangePlotPropertiesMenu        matlab.ui.container.Menu
        CSDPlotMenu                     matlab.ui.container.Menu
        FontSizeMenu                    matlab.ui.container.Menu
        LineWidthTriggerMenu            matlab.ui.container.Menu
        ColorTriggerMenu                matlab.ui.container.Menu
        BackgorundColorMenu             matlab.ui.container.Menu
        XLabelMenu                      matlab.ui.container.Menu
        YLabelMenu                      matlab.ui.container.Menu
        CLabelMenu                      matlab.ui.container.Menu
        RESETMenu                       matlab.ui.container.Menu
        SaveCostumeAppearanceMenu       matlab.ui.container.Menu
        DeleteCostumeAppearanceMenu     matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        CloseButton                     matlab.ui.control.Button
        CSDParameterPanel               matlab.ui.container.Panel
        CSDParameterLabel               matlab.ui.control.Label
        AutoClimButton                  matlab.ui.control.Button
        ClimminmaxEditField             matlab.ui.control.EditField
        ClimminmaxLabel                 matlab.ui.control.Label
        EventNumberSelectionEditField   matlab.ui.control.EditField
        TriggerNumberSelectionmatlabExpressionsLabel  matlab.ui.control.Label
        HammWindowEditFieldLabel        matlab.ui.control.Label
        HammWindowEditField             matlab.ui.control.EditField
        DataTypeDropDown                matlab.ui.control.DropDown
        DataTypeDropDownLabel           matlab.ui.control.Label
        ChannelSelectionEditField       matlab.ui.control.EditField
        ChannelSelectionEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        EventRelatedWindow = []; % Description
        climCSD = [];
        EventTime = [];
        TimearoundEvent = [];
        ChannelSelection = [];
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
        Mainapp = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EventRelatedWindow, Mainapp)
            movegui(app.CSDAnalysisUIFigure, 'center');
            
            app.EventRelatedWindow = EventRelatedWindow;
            app.Mainapp = Mainapp;

            app = Utility_Change_Light_Dark_Mode(app,"EventCSD");

            app.UIAxes.Color = app.Mainapp.PlotAppearance.CSDWindow.BackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.CSDWindow.FontSize;

            if ~isfield(app.Mainapp.Data,'Events')
                msgbox("No events extracted. Please extract events first!. Exiting.");
                delete(app);
                return;
            end

            %% Fill Event Channel Selection, Event umber selection and channel selection fields

            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                app.DataTypeDropDown.Items{1} = 'Raw Event Related Data';
                app.DataTypeDropDown.Items{2} = 'Preprocessed Event Related Data';
            else
                app.DataTypeDropDown.Items{1} = 'Raw Event Related Data';
            end
            
            % Channelselection

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                app.EventNumberSelectionEditField.Value = ['1:',num2str(size(app.Mainapp.Data.EventRelatedData,2))];
            else
                app.EventNumberSelectionEditField.Value = ['1:',num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2))];
            end

            %% Extract GUI Infos
            spaceindicie = strfind(app.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));
            app.TimearoundEvent(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));
            
            if contains(app.EventNumberSelectionEditField.Value,',')
                if ~contains(app.EventNumberSelectionEditField.Value,'[') || ~contains(app.EventNumberSelectionEditField.Value,']')
                    app.EventNumberSelectionEditField.Value = strcat('[',app.EventNumberSelectionEditField.Value,']');
                end
            end
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                AlleventNr = size(app.Mainapp.Data.EventRelatedData,2);
                try
                    EventNr = eval(app.EventNumberSelectionEditField.Value);
                catch
                    EventNr = 1:AlleventNr;
                    app.EventNumberSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
    
                if length(EventNr)>AlleventNr || sum(EventNr>AlleventNr) ~= 0 || isempty(EventNr)
                    msgbox("Error: Trigger range exceeds existing number of triggers! Plotting all trigger.")
                    EventNr = 1:AlleventNr;
                    app.EventNumberSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
            else
                AlleventNr = size(app.Mainapp.Data.PreprocessedEventRelatedData,2);
                try
                    EventNr = eval(app.EventNumberSelectionEditField.Value);
                catch
                    EventNr = 1:AlleventNr;
                    app.EventNumberSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
    
                if length(EventNr)>AlleventNr || sum(EventNr>AlleventNr) ~= 0 || isempty(EventNr)
                    msgbox("Error: Trigger range exceeds existing number of triggers! Plotting all trigger.")
                    EventNr = 1:AlleventNr;
                    app.EventNumberSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
            end
            
            % Structure holding csd window specific infos
            CSD.ChannelSpacing = app.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            CSD.SelectedChannel = app.Mainapp.ActiveChannel;
            
            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end
            
            %% Plot CSD
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end

            app = Utility_Change_Light_Dark_Mode(app,"EventCSD");
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Mainapp.EventLFPCSD = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event CSD Window')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app)
        end

        % Value changed function: EventNumberSelectionEditField
        function EventNumberSelectionEditFieldValueChanged(app, event)
            %% Check Proper Inputs
            
            if contains(app.EventNumberSelectionEditField.Value,',')
                if ~contains(app.EventNumberSelectionEditField.Value,'[') || ~contains(app.EventNumberSelectionEditField.Value,']')
                    app.EventNumberSelectionEditField.Value = strcat('[',app.EventNumberSelectionEditField.Value,']');
                end
            end
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                AlleventNr = size(app.Mainapp.Data.EventRelatedData,2);
                try
                    EventNr = eval(app.EventNumberSelectionEditField.Value);
                catch
                    EventNr = 1:AlleventNr;
                    app.EventNumberSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
    
                if length(EventNr)>AlleventNr || sum(EventNr>AlleventNr) ~= 0 || isempty(EventNr)
                    msgbox("Error: Trigger range exceeds existing number of triggers! Plotting all trigger.")
                    EventNr = 1:AlleventNr;
                    app.EventNumberSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
            else
                AlleventNr = size(app.Mainapp.Data.PreprocessedEventRelatedData,2);
                try
                    EventNr = eval(app.EventNumberSelectionEditField.Value);
                catch
                    EventNr = 1:AlleventNr;
                    app.EventNumberSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
    
                if length(EventNr)>AlleventNr || sum(EventNr>AlleventNr) ~= 0 || isempty(EventNr)
                    msgbox("Error: Trigger range exceeds existing number of triggers! Plotting all trigger.")
                    EventNr = 1:AlleventNr;
                    app.EventNumberSelectionEditField.Value = strcat(num2str(min(EventNr)),':',num2str(max(EventNr)));
                end
            end

            %% Extract Values to do calculations

            CSD.ChannelSpacing = app.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            CSD.SelectedChannel = app.Mainapp.ActiveChannel;

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.Color = 'k';        % Sets the color of the label text
            cb.Label.String = app.Mainapp.PlotAppearance.CSDWindow.CLabel;
            cb.Label.Rotation = 270;
            cb.FontSize =  app.Mainapp.PlotAppearance.CSDWindow.FontSize;  
        end

        % Value changed function: HammWindowEditField
        function HammWindowEditFieldValueChanged(app, event)
            %% Check Proper Inputs
            [app.HammWindowEditField.Value] = Utility_SimpleCheckInputs(app.HammWindowEditField.Value,"One",'5',0,0);

            EventNr = eval(app.EventNumberSelectionEditField.Value);

            CSD.ChannelSpacing = app.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            CSD.SelectedChannel = app.Mainapp.ActiveChannel;

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.Color = 'k';        % Sets the color of the label text
            cb.Label.String = app.Mainapp.PlotAppearance.CSDWindow.CLabel;
            cb.Label.Rotation = 270;
            cb.FontSize =  app.Mainapp.PlotAppearance.CSDWindow.FontSize;  
        end

        % Callback function
        function ChannelSelectionDropDownValueChanged(app, event)
            
        end

        % Value changed function: ClimminmaxEditField
        function ClimminmaxEditFieldValueChanged(app, event)
            [app.ClimminmaxEditField.Value] = Utility_SimpleCheckInputs(app.ClimminmaxEditField.Value,"Two",strcat(''),0,1);

            ErrorCLIM = 0;
            if ~isempty(app.ClimminmaxEditField.Value)
                
                if ErrorCLIM == 0
                    value = app.ClimminmaxEditField.Value;
                    indicesep = find(value == ',');
                    climcostumERP(1,1) = str2double(value(1:indicesep-1));
                    climcostumERP(1,2) = str2double(value(indicesep+1:end));
        
                    app.UIAxes.CLim = climcostumERP;
                end
            end
            
            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.Color = 'k';        % Sets the color of the label text
            cb.Label.String = app.Mainapp.PlotAppearance.CSDWindow.CLabel;
            cb.Label.Rotation = 270;
            cb.FontSize =  app.Mainapp.PlotAppearance.CSDWindow.FontSize;  
        end

        % Button pushed function: AutoClimButton
        function AutoClimButtonPushed(app, event)
            if ~isempty(app.climCSD)
                app.UIAxes.CLim = app.climCSD;
                app.ClimminmaxEditField.Value = "";
                cb = colorbar(app.UIAxes);
                cb.Color = 'k';              % Sets tick mark and label color to black
                cb.Label.Color = 'k';        % Sets the color of the label text
                cb.Label.String = app.Mainapp.PlotAppearance.CSDWindow.CLabel;
                cb.Label.Rotation = 270;
                cb.FontSize =  app.Mainapp.PlotAppearance.CSDWindow.FontSize;  
            end
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                texttoshow = ['1:',num2str(size(app.Mainapp.Data.EventRelatedData,2))];
                app.EventNumberSelectionEditField.Value = texttoshow;
            else
               texttoshow = ['1:',num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2))];
                app.EventNumberSelectionEditField.Value = texttoshow;
            end
            
            EventNr = eval(app.EventNumberSelectionEditField.Value);

            CSD.ChannelSpacing = app.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            CSD.SelectedChannel = app.Mainapp.ActiveChannel;

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end

            cb = colorbar(app.UIAxes);
            cb.Color = 'k';              % Sets tick mark and label color to black
            cb.Label.Color = 'k';        % Sets the color of the label text
            cb.Label.String = app.Mainapp.PlotAppearance.CSDWindow.CLabel;
            cb.Label.Rotation = 270;
            cb.FontSize =  app.Mainapp.PlotAppearance.CSDWindow.FontSize;  
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.CSDAnalysisUIFigure,fullfile(path,file))
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.CSDAnalysisUIFigure,fullfile(path,file))
        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            app.UIAxes.Color = app.Mainapp.PlotAppearance.CSDWindow.BackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.CSDWindow.FontSize;

            app.TwoORThreeD = "TwoD";

            EventNr = eval(app.EventNumberSelectionEditField.Value);

            CSD.ChannelSpacing = app.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            CSD.SelectedChannel = app.Mainapp.ActiveChannel;

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end

        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Current Source Density Analysis");
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Current Source Density Analysis");
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".csv",app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Current Source Density Analysis");
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            app.UIAxes.Color = app.Mainapp.PlotAppearance.CSDWindow.BackgroundColor;
            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.CSDWindow.FontSize;

            app.TwoORThreeD = "ThreeD";

            EventNr = eval(app.EventNumberSelectionEditField.Value);

            CSD.ChannelSpacing = app.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            CSD.SelectedChannel = app.Mainapp.ActiveChannel;


            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app.Mainapp,"CSDWindowDataFontSize");
            waitfor(h);

            app.UIAxes.FontSize = app.Mainapp.PlotAppearance.CSDWindow.FontSize;
           
        end

        % Menu selected function: LineWidthTriggerMenu
        function LineWidthTriggerMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app.Mainapp,"CSDEventLineWidth");
            waitfor(h);

            EventNr = eval(app.EventNumberSelectionEditField.Value);

            CSD.ChannelSpacing = app.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            CSD.SelectedChannel = app.Mainapp.ActiveChannel;


            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end

        end

        % Menu selected function: ColorTriggerMenu
        function ColorTriggerMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.CSDWindow.TriggerColor = selectedColor;
            end

            EventNr = eval(app.EventNumberSelectionEditField.Value);

            CSD.ChannelSpacing = app.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            CSD.SelectedChannel = app.Mainapp.ActiveChannel;


            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.Mainapp.PlotAppearance.CSDWindow.TriggerColor = selectedColor;
                app.UIAxes.Color = app.Mainapp.PlotAppearance.CSDWindow.BackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app.Mainapp,"CSDXLabel");
            waitfor(h);

            EventNr = eval(app.EventNumberSelectionEditField.Value);

            CSD.ChannelSpacing = app.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            CSD.SelectedChannel = app.Mainapp.ActiveChannel;


            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app.Mainapp,"CSDYLabel");
            waitfor(h);

            EventNr = eval(app.EventNumberSelectionEditField.Value);

            CSD.ChannelSpacing = app.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            CSD.SelectedChannel = app.Mainapp.ActiveChannel;


            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: CLabelMenu
        function CLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'clabel',app.Mainapp,"CSDCLabel");
            waitfor(h);

            EventNr = eval(app.EventNumberSelectionEditField.Value);

            CSD.ChannelSpacing = app.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            CSD.SelectedChannel = app.Mainapp.ActiveChannel;


            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            [app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("CSDPlot",app.Mainapp.PlotAppearance);

            EventNr = eval(app.EventNumberSelectionEditField.Value);

            CSD.ChannelSpacing = app.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            CSD.SelectedChannel = app.Mainapp.ActiveChannel;


            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end
        end

        % Menu selected function: SaveCostumeAppearanceMenu
        function SaveCostumeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCostumeAppearanceMenu
        function DeleteCostumeAppearanceMenuSelected(app, event)
            app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.Mainapp.PlotAppearance,app.Mainapp.executableFolder,"Delete");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CSDAnalysisUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {473, 473};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {367, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: ChannelSelectionEditField
        function ChannelSelectionEditFieldValueChanged(app, event)
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [app.ChannelSelectionEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.EventRelatedData,1))),1,0);
            else
                [app.ChannelSelectionEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionEditField.Value,"Two",strcat('1,',num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,1))),1,0);
            end

            EventNr = eval(app.EventNumberSelectionEditField.Value);

            CSD.ChannelSpacing = app.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            CSD.SelectedChannel = app.Mainapp.ActiveChannel;

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            else
                [app.climCSD,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,[],app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.EventTime,CSD.SelectedChannel,CSD,[],[],[],app.TwoORThreeD,app.Mainapp.CurrentPlotData,app.Mainapp.PlotAppearance);
            end
            
        end

        % Close request function: CSDAnalysisUIFigure
        function CSDAnalysisUIFigureCloseRequest(app, event)
            app.Mainapp.EventLFPCSD = [];

            if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                    if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event CSD Window')
                        app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                        break;
                    end
                end
            else % if no probe view available
 
            end

            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CSDAnalysisUIFigure = uifigure('Visible', 'off');
            app.CSDAnalysisUIFigure.Name = 'Event CSD Window';
            app.CSDAnalysisUIFigure.Icon = 'Logo.png';
            app.CSDAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @CSDAnalysisUIFigureCloseRequest, true);
            app.CSDAnalysisUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SnapshotMenu = uimenu(app.CSDAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu = uimenu(app.CSDAnalysisUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.CSDPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.CSDPlotMenu.Text = 'CSD Plot';
            app.FontSizeMenu = uimenu(app.CSDPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthTriggerMenu = uimenu(app.CSDPlotMenu);
            app.LineWidthTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTriggerMenuSelected, true);
            app.LineWidthTriggerMenu.Text = 'LineWidth Trigger';
            app.ColorTriggerMenu = uimenu(app.CSDPlotMenu);
            app.ColorTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorTriggerMenuSelected, true);
            app.ColorTriggerMenu.Text = 'Color Trigger';
            app.BackgorundColorMenu = uimenu(app.CSDPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.CSDPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.CSDPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.CLabelMenu = uimenu(app.CSDPlotMenu);
            app.CLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @CLabelMenuSelected, true);
            app.CLabelMenu.Text = 'CLabel';
            app.RESETMenu = uimenu(app.CSDPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.SaveCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCostumeAppearanceMenuSelected, true);
            app.SaveCostumeAppearanceMenu.Text = 'Save Costume Appearance';
            app.DeleteCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCostumeAppearanceMenuSelected, true);
            app.DeleteCostumeAppearanceMenu.Text = 'Delete Costume Appearance';
            app.ExportDataMenu = uimenu(app.CSDAnalysisUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.matMenu = uimenu(app.ExportDataMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ExportDataMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.GridLayout = uigridlayout(app.CSDAnalysisUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.ChannelSelectionEditFieldLabel = uilabel(app.CSDAnalysisUIFigure);
            app.ChannelSelectionEditFieldLabel.Enable = 'off';
            app.ChannelSelectionEditFieldLabel.Visible = 'off';
            app.ChannelSelectionEditFieldLabel.Text = 'Channel Selection';
            app.ChannelSelectionEditField = uieditfield(app.CSDAnalysisUIFigure, 'text');
            app.ChannelSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionEditFieldValueChanged, true);
            app.ChannelSelectionEditField.Editable = 'off';
            app.ChannelSelectionEditField.Enable = 'off';
            app.ChannelSelectionEditField.Visible = 'off';
            app.CSDParameterPanel = uipanel(app.CSDAnalysisUIFigure);
            app.CSDParameterPanel.Title = 'CSD Parameter';
            app.DataTypeDropDownLabel = uilabel(app.CSDParameterPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.CSDParameterPanel);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = {};
            app.HammWindowEditField = uieditfield(app.CSDParameterPanel, 'text');
            app.HammWindowEditField.ValueChangedFcn = createCallbackFcn(app, @HammWindowEditFieldValueChanged, true);
            app.HammWindowEditField.Value = '5';
            app.HammWindowEditFieldLabel = uilabel(app.CSDParameterPanel);
            app.HammWindowEditFieldLabel.Text = 'Hamm Window';
            app.TriggerNumberSelectionmatlabExpressionsLabel = uilabel(app.CSDParameterPanel);
            app.TriggerNumberSelectionmatlabExpressionsLabel.Text = {'Trigger Number'; 'Selection (Matlab Expressions)'};
            app.EventNumberSelectionEditField = uieditfield(app.CSDParameterPanel, 'text');
            app.EventNumberSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @EventNumberSelectionEditFieldValueChanged, true);
            app.ClimminmaxLabel = uilabel(app.CSDParameterPanel);
            app.ClimminmaxLabel.Text = 'Clim [min,max]';
            app.ClimminmaxEditField = uieditfield(app.CSDParameterPanel, 'text');
            app.ClimminmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ClimminmaxEditFieldValueChanged, true);
            app.AutoClimButton = uibutton(app.CSDParameterPanel, 'push');
            app.AutoClimButton.ButtonPushedFcn = createCallbackFcn(app, @AutoClimButtonPushed, true);
            app.AutoClimButton.Text = 'Auto Clim';
            app.CSDParameterLabel = uilabel(app.CSDParameterPanel);
            app.CSDParameterLabel.Text = 'CSD Parameter';
            app.CloseButton = uibutton(app.CSDAnalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.CSDAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSD_Analysis_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CSDAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CSDAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CSDAnalysisUIFigure)
        end
    end
end