classdef ERP_Analysis_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventRelatedAnalysisUIFigure   matlab.ui.Figure
        SnapshotMenu                   matlab.ui.container.Menu
        SaveSnapshotofMainWindowMenu   matlab.ui.container.Menu
        PNGMenu                        matlab.ui.container.Menu
        JPGMenu                        matlab.ui.container.Menu
        ExportDataasMenu               matlab.ui.container.Menu
        matMenu                        matlab.ui.container.Menu
        txtMenu                        matlab.ui.container.Menu
        csvMenu                        matlab.ui.container.Menu
        Slider                         matlab.ui.control.Slider
        ChannelSpacingLabel            matlab.ui.control.Label
        CloseButton                    matlab.ui.control.Button
        LocalFieldPotentialAnalysisPanel  matlab.ui.container.Panel
        DataTypeDropDown               matlab.ui.control.DropDown
        DataTypeDropDownLabel          matlab.ui.control.Label
        ChannelSelectionDropDown       matlab.ui.control.EditField
        ChannelLabel                   matlab.ui.control.Label
        EventNumberSelectionEditField  matlab.ui.control.EditField
        EventNumberSelectionEditFieldLabel  matlab.ui.control.Label
        NeuromodToolboxLabel           matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        EventRelatedPotentialWindowLabel  matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu3                   matlab.ui.container.ContextMenu
        ChangeTitleMenu                matlab.ui.container.Menu
        ChangeXLabelMenu               matlab.ui.container.Menu
        ChangeYLabelMenu               matlab.ui.container.Menu
        ChangeXlimMenu                 matlab.ui.container.Menu
        ChangeYlimMenu                 matlab.ui.container.Menu
        ChangeLegendMenu               matlab.ui.container.Menu
        ChangeFontsizeMenu             matlab.ui.container.Menu
        ContextMenu3_2                 matlab.ui.container.ContextMenu
        ChangeTitleMenu_2              matlab.ui.container.Menu
        ChangeXLabelMenu_2             matlab.ui.container.Menu
        ChangeYLabelMenu_2             matlab.ui.container.Menu
        ChangeXlimMenu_2               matlab.ui.container.Menu
        ChangeYlimMenu_2               matlab.ui.container.Menu
        ChangeLegendMenu_2             matlab.ui.container.Menu
        ChangeFontsizeMenu_2           matlab.ui.container.Menu
    end

    
    properties (Access = public)
        EventRelatedWindow = []; % Description
        EventTime = [];
        TimearoundEvent = [];
        colorMap = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EventRelatedWindow)
            movegui(app.EventRelatedAnalysisUIFigure, 'center');

            app.EventRelatedWindow = EventRelatedWindow;

            if ~isfield(app.EventRelatedWindow.Mainapp.Data,'Events')
                msgbox("No events extracted. Please extract events first!. Exiting.");
                delete(app);
                return;
            end
            
            spaceindicie = find(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange == ' ');
            Timebefore = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie);
            Timeafter = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end);
            
            app.colorMap = app.EventRelatedWindow.Mainapp.tempcolorMapset;

            %% Fill Event Channel Selection, Event umber selection and channel selection fields

            if isfield(app.EventRelatedWindow.Mainapp.Data,'PreprocessedEventRelatedData')
                app.DataTypeDropDown.Items{1} = 'Raw Event Related Data';
                app.DataTypeDropDown.Items{2} = 'Preprocessed Event Related Data';
            else
                app.DataTypeDropDown.Items{1} = 'Raw Event Related Data';
            end

            texttoshow = ['1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2))];
            app.EventNumberSelectionEditField.Value = texttoshow;

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Raw")
                texttoshowChannel = ['1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.Raw,1))];
            end

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed")
                texttoshowChannel = ['1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.Preprocessed,1))];
            end

            app.ChannelSelectionDropDown.Value = texttoshowChannel;
            
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP
            
            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            Commaindicie = strfind(app.ChannelSelectionDropDown.Value,",");
            ChannelSelection(1) = str2double(app.ChannelSelectionDropDown.Value(1:Commaindicie-1));
            ChannelSelection(2) = str2double(app.ChannelSelectionDropDown.Value(Commaindicie+1:end));

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,Trialplot,Meanplot,Eventplot] = Event_Module_Compute_and_Plot_ERP_CSD(app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,ChannelSelection,[],app.colorMap,app.Slider.Value,'All');
            else
                [~,Trialplot,Meanplot,Eventplot] = Event_Module_Compute_and_Plot_ERP_CSD(app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,ChannelSelection,[],app.colorMap,app.Slider.Value,'All');
            end

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: EventNumberSelectionEditField
        function EventNumberSelectionEditFieldValueChanged(app, event)
            %% Check if user inputs are correct

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [app.EventNumberSelectionEditField.Value] = Utility_SimpleCheckInputs(app.EventNumberSelectionEditField.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2))),1,0);
            else
                [app.EventNumberSelectionEditField.Value] = Utility_SimpleCheckInputs(app.EventNumberSelectionEditField.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,2))),1,0);
            end

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end

            Commaindicie = strfind(app.ChannelSelectionDropDown.Value,",");
            ChannelSelection(1) = str2double(app.ChannelSelectionDropDown.Value(1:Commaindicie-1));
            ChannelSelection(2) = str2double(app.ChannelSelectionDropDown.Value(Commaindicie+1:end));

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                Event_Module_Compute_and_Plot_ERP_CSD(app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,ChannelSelection,[],app.colorMap,app.Slider.Value,'All');
            else
                Event_Module_Compute_and_Plot_ERP_CSD(app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,ChannelSelection,[],app.colorMap,app.Slider.Value,'All');
            end
        end

        % Value changed function: ChannelSelectionDropDown
        function ChannelSelectionDropDownValueChanged(app, event)
            %% Check if user inputs are correct
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [app.ChannelSelectionDropDown.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionDropDown.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,1))),1,0);
            else
                [app.ChannelSelectionDropDown.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionDropDown.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,1))),1,0);
            end

            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end
            
            Commaindicie = strfind(app.ChannelSelectionDropDown.Value,",");
            ChannelSelection(1) = str2double(app.ChannelSelectionDropDown.Value(1:Commaindicie-1));
            ChannelSelection(2) = str2double(app.ChannelSelectionDropDown.Value(Commaindicie+1:end));

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                Event_Module_Compute_and_Plot_ERP_CSD(app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,ChannelSelection,[],app.colorMap,app.Slider.Value,'SingleERPOnly');
            else
                Event_Module_Compute_and_Plot_ERP_CSD(app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,ChannelSelection,[],app.colorMap,app.Slider.Value,'SingleERPOnly');
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end
            
            Commaindicie = strfind(app.ChannelSelectionDropDown.Value,",");
            ChannelSelection(1) = str2double(app.ChannelSelectionDropDown.Value(1:Commaindicie-1));
            ChannelSelection(2) = str2double(app.ChannelSelectionDropDown.Value(Commaindicie+1:end));

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                Event_Module_Compute_and_Plot_ERP_CSD(app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,ChannelSelection,[],app.colorMap,app.Slider.Value,'MultipleERPOnly');
            else
                Event_Module_Compute_and_Plot_ERP_CSD(app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,ChannelSelection,[],app.colorMap,app.Slider.Value,'MultipleERPOnly');
            end
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            %% Plot ERP

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end
            
            Commaindicie = strfind(app.ChannelSelectionDropDown.Value,",");
            ChannelSelection(1) = str2double(app.ChannelSelectionDropDown.Value(1:Commaindicie-1));
            ChannelSelection(2) = str2double(app.ChannelSelectionDropDown.Value(Commaindicie+1:end));

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                Event_Module_Compute_and_Plot_ERP_CSD(app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,ChannelSelection,[],app.colorMap,app.Slider.Value,'All');
            else
                Event_Module_Compute_and_Plot_ERP_CSD(app.UIAxes,app.UIAxes_2,app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,ChannelSelection,[],app.colorMap,app.Slider.Value,'All');
            end
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.EventRelatedAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.EventRelatedAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: ChangeTitleMenu
        function ChangeTitleMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'title');
        end

        % Menu selected function: ChangeXLabelMenu
        function ChangeXLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu
        function ChangeYLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu
        function ChangeXlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlim');
        end

        % Menu selected function: ChangeYlimMenu
        function ChangeYlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylim');
        end

        % Menu selected function: ChangeLegendMenu
        function ChangeLegendMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu
        function ChangeFontsizeMenuSelected(app, event)
            
        end

        % Menu selected function: ChangeTitleMenu_2
        function ChangeTitleMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_2,'title');
        end

        % Menu selected function: ChangeXLabelMenu_2
        function ChangeXLabelMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_2,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu_2
        function ChangeYLabelMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_2,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu_2
        function ChangeXlimMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_2,'xlim');
        end

        % Menu selected function: ChangeYlimMenu_2
        function ChangeYlimMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_2,'ylim');
        end

        % Menu selected function: ChangeLegendMenu_2
        function ChangeLegendMenu_2Selected(app, event)
            Figure_Property_Change_Window(app.UIAxes_2,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu_2
        function ChangeFontsizeMenu_2Selected(app, event)
            
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = str2double(app.EventRelatedWindow.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.EventRelatedWindow.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.EventRelatedWindow.Mainapp.CurrentTimePoints/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.EventRelatedWindow.Mainapp.CurrentTimePoints/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.EventRelatedWindow.Mainapp.CurrentTimePoints/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,".mat",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event_ERP");
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = str2double(app.EventRelatedWindow.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.EventRelatedWindow.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.EventRelatedWindow.Mainapp.CurrentTimePoints/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.EventRelatedWindow.Mainapp.CurrentTimePoints/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.EventRelatedWindow.Mainapp.CurrentTimePoints/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,".txt",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event_ERP");
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = str2double(app.EventRelatedWindow.Mainapp.TimeRangeViewBox.Value(1:end-1));
            if app.EventRelatedWindow.Mainapp.PreprocDataPlotCheckBox.Value == 1
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampleFactor')
                    StartTime = app.EventRelatedWindow.Mainapp.CurrentTimePoints/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate;
                else
                    StartTime = app.EventRelatedWindow.Mainapp.CurrentTimePoints/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate;
                end
            else
                StartTime = app.EventRelatedWindow.Mainapp.CurrentTimePoints/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate;
            end

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,".csv",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event_ERP");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventRelatedAnalysisUIFigure = uifigure('Visible', 'off');
            app.EventRelatedAnalysisUIFigure.Name = 'Event Related Analysis';
            app.EventRelatedAnalysisUIFigure.Icon = 'Logo.png';
            app.SnapshotMenu = uimenu(app.EventRelatedAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofMainWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofMainWindowMenu.Text = 'Save Snapshot of Main Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofMainWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofMainWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.ExportDataasMenu = uimenu(app.EventRelatedAnalysisUIFigure);
            app.ExportDataasMenu.Text = '   Export Data as   ';
            app.matMenu = uimenu(app.ExportDataasMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataasMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ExportDataasMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.UIAxes = uiaxes(app.EventRelatedAnalysisUIFigure);
            app.UIAxes_2 = uiaxes(app.EventRelatedAnalysisUIFigure);
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.EventRelatedPotentialWindowLabel = uilabel(app.EventRelatedAnalysisUIFigure);
            app.EventRelatedPotentialWindowLabel.Text = 'Event Related Potential Window';
            app.Image = uiimage(app.EventRelatedAnalysisUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.EventRelatedAnalysisUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.LocalFieldPotentialAnalysisPanel = uipanel(app.EventRelatedAnalysisUIFigure);
            app.LocalFieldPotentialAnalysisPanel.Title = 'Local Field Potential Analysis';
            app.EventNumberSelectionEditFieldLabel = uilabel(app.LocalFieldPotentialAnalysisPanel);
            app.EventNumberSelectionEditFieldLabel.Text = {'Event Number'; 'Selection'};
            app.EventNumberSelectionEditField = uieditfield(app.LocalFieldPotentialAnalysisPanel, 'text');
            app.EventNumberSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @EventNumberSelectionEditFieldValueChanged, true);
            app.ChannelLabel = uilabel(app.LocalFieldPotentialAnalysisPanel);
            app.ChannelLabel.Text = {'Channel Selection'; '[from,to]'};
            app.ChannelSelectionDropDown = uieditfield(app.LocalFieldPotentialAnalysisPanel, 'text');
            app.ChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDownValueChanged, true);
            app.DataTypeDropDownLabel = uilabel(app.LocalFieldPotentialAnalysisPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.LocalFieldPotentialAnalysisPanel);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = {};
            app.CloseButton = uibutton(app.EventRelatedAnalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ChannelSpacingLabel = uilabel(app.EventRelatedAnalysisUIFigure);
            app.ChannelSpacingLabel.Text = {'Channel '; 'Spacing'};
            app.Slider = uislider(app.EventRelatedAnalysisUIFigure);
            app.Slider.Limits = [0.001 0.1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 0.001;
            app.ContextMenu3 = uicontextmenu(app.EventRelatedAnalysisUIFigure);
            app.ChangeTitleMenu = uimenu(app.ContextMenu3);
            app.ChangeTitleMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenuSelected, true);
            app.ChangeTitleMenu.Text = 'Change Title';
            app.ChangeXLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeXLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenuSelected, true);
            app.ChangeXLabelMenu.Text = 'Change X Label';
            app.ChangeYLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeYLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenuSelected, true);
            app.ChangeYLabelMenu.Text = 'Change Y Label';
            app.ChangeXlimMenu = uimenu(app.ContextMenu3);
            app.ChangeXlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenuSelected, true);
            app.ChangeXlimMenu.Text = 'Change Xlim';
            app.ChangeYlimMenu = uimenu(app.ContextMenu3);
            app.ChangeYlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenuSelected, true);
            app.ChangeYlimMenu.Text = 'Change Ylim';
            app.ChangeLegendMenu = uimenu(app.ContextMenu3);
            app.ChangeLegendMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenuSelected, true);
            app.ChangeLegendMenu.Text = 'Change Legend';
            app.ChangeFontsizeMenu = uimenu(app.ContextMenu3);
            app.ChangeFontsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenuSelected, true);
            app.ChangeFontsizeMenu.Text = 'Change Fontsize';
            % Assign app.ContextMenu3
            app.UIAxes.ContextMenu = app.ContextMenu3;
            app.ContextMenu3_2 = uicontextmenu(app.EventRelatedAnalysisUIFigure);
            app.ChangeTitleMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeTitleMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenu_2Selected, true);
            app.ChangeTitleMenu_2.Text = 'Change Title';
            app.ChangeXLabelMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeXLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenu_2Selected, true);
            app.ChangeXLabelMenu_2.Text = 'Change X Label';
            app.ChangeYLabelMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeYLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenu_2Selected, true);
            app.ChangeYLabelMenu_2.Text = 'Change Y Label';
            app.ChangeXlimMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeXlimMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenu_2Selected, true);
            app.ChangeXlimMenu_2.Text = 'Change Xlim';
            app.ChangeYlimMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeYlimMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenu_2Selected, true);
            app.ChangeYlimMenu_2.Text = 'Change Ylim';
            app.ChangeLegendMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeLegendMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenu_2Selected, true);
            app.ChangeLegendMenu_2.Text = 'Change Legend';
            app.ChangeFontsizeMenu_2 = uimenu(app.ContextMenu3_2);
            app.ChangeFontsizeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenu_2Selected, true);
            app.ChangeFontsizeMenu_2.Text = 'Change Fontsize';
            % Assign app.ContextMenu3_2
            app.UIAxes_2.ContextMenu = app.ContextMenu3_2;
            % Show the figure after all components are created
            app.EventRelatedAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ERP_Analysis_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EventRelatedAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EventRelatedAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventRelatedAnalysisUIFigure)
        end
    end
end