classdef Time_Frequency_Power_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TFAnalysisUIFigure              matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        Show2DimensionalPlotsMenu       matlab.ui.container.Menu
        Show3DimensionalPlotsMenu       matlab.ui.container.Menu
        ExportDataasMenu                matlab.ui.container.Menu
        matMenu                         matlab.ui.container.Menu
        txtMenu                         matlab.ui.container.Menu
        csvMenu                         matlab.ui.container.Menu
        Panel                           matlab.ui.container.Panel
        WaveletTypeDropDown             matlab.ui.control.DropDown
        WaveletTypeDropDownLabel        matlab.ui.control.Label
        PhaseindependentCheckBox        matlab.ui.control.CheckBox
        AddonsLabel                     matlab.ui.control.Label
        PhaselockedCheckBox             matlab.ui.control.CheckBox
        NonphaselockedCheckBox          matlab.ui.control.CheckBox
        PlottedDataLabel                matlab.ui.control.Label
        IntertrialPhaseClusteringCheckBox  matlab.ui.control.CheckBox
        TimeFrequencyCheckBox           matlab.ui.control.CheckBox
        WaveletAnalysisParameterPanel_2  matlab.ui.container.Panel
        ClimminmaxEditField             matlab.ui.control.EditField
        ClimminmaxLabel                 matlab.ui.control.Label
        AutoClimButton                  matlab.ui.control.Button
        FrequencyRangeminmaxstepsEditField  matlab.ui.control.EditField
        FrequRangeminmaxstepsEditFieldLabel  matlab.ui.control.Label
        CycleWidthfromto23EditField     matlab.ui.control.EditField
        CycleWidthfromtoEditFieldLabel  matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        EventandDataChannelSelectionPanel  matlab.ui.container.Panel
        DataTypeDropDown                matlab.ui.control.DropDown
        DataTypeDropDownLabel           matlab.ui.control.Label
        ChannelSelectionDropDown        matlab.ui.control.EditField
        ChannelLabel                    matlab.ui.control.Label
        EventNumberSelectionEditField   matlab.ui.control.EditField
        EventNumberSelectionEditFieldLabel  matlab.ui.control.Label
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TimeFrequencyPowerWindowLabel   matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        EventRelatedWindow = []; % Description
        climsTF = [];
        PlotSelection = [];
        TFInfos = [];
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EventRelatedWindow)
            movegui(app.TFAnalysisUIFigure, 'center');

            app.EventRelatedWindow = EventRelatedWindow;

            if ~isfield(app.EventRelatedWindow.Mainapp.Data,'Events')
                msgbox("No events extracted. Please extract events first!. Exiting.");
                delete(app);
                return;
            end

            if ~isfield(app.EventRelatedWindow.Mainapp.Data,'Preprocessed')
                msgbox("No Preprocessed data found. Please low pass filter data first!. Exiting.");
                delete(app);
                return;
            end

            %% Fill Event Channel Selection, Event umber selection and channel selection fields
            
            % Data Channel
            texttoshow = ['1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.Preprocessed,1))];

            if isfield(app.EventRelatedWindow.Mainapp.Data,'PreprocessedEventRelatedData')
                app.DataTypeDropDown.Items{1} = 'Raw Event Data';
                app.DataTypeDropDown.Items{2} = 'Preprocessed Event Data';
            else
                app.DataTypeDropDown.Items{1} = 'Raw Event Data';
            end

            app.EventNumberSelectionEditField.Value = strcat("1,",num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2)));

            app.ChannelSelectionDropDown.Value = texttoshow;
                        
            app.TFInfos.PlottedData = "TF";
            app.TFInfos.Addons = "Total";

            % Extract Cell with eventindicies of selected event channel
            spaceindicie = find(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange == ' ');
            app.TFInfos.Timearoundevent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)));
            app.TFInfos.Timearoundevent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));
            
            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.EventRelatedWindow.Mainapp.Data,app.WaveletTypeDropDown.Value,app.ChannelSelectionDropDown.Value,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                end
            else
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                end
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: EventNumberSelectionEditField
        function EventNumberSelectionEditFieldValueChanged(app, event)
            if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                [app.EventNumberSelectionEditField.Value] = Utility_SimpleCheckInputs(app.EventNumberSelectionEditField.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2))),1,0);
            else
                [app.EventNumberSelectionEditField.Value] = Utility_SimpleCheckInputs(app.EventNumberSelectionEditField.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,2))),1,0);
            end

            value = app.EventNumberSelectionEditField.Value;
            
            CommaIndex = strfind(value,",");
            EventNrRange(1) = str2double(value(1:CommaIndex-1));
            EventNrRange(2) = str2double(value(CommaIndex+1:end));
            
            if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                if EventNrRange(2) > size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2)
                    msgbox("More events selected than available for this event channel. Setting second limit to max event number");
                    app.EventNumberSelectionEditField.Value = strcat(num2str(EventNrRange(1)),",",num2str(length(app.EventRelatedWindow.Mainapp.Data.Events{str2double(app.EventChannelSelectionDropDown.Value)})));
                end
            else
                if EventNrRange(2) > size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,2)
                    msgbox("More events selected than available for this event channel. Setting second limit to max event number");
                    app.EventNumberSelectionEditField.Value = strcat(num2str(EventNrRange(1)),",",num2str(length(app.EventRelatedWindow.Mainapp.Data.Events{str2double(app.EventChannelSelectionDropDown.Value)})));
                end
            end

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.EventRelatedWindow.Mainapp.Data,app.WaveletTypeDropDown.Value,app.ChannelSelectionDropDown.Value,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                end
            else
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                end
            end
        end

        % Value changed function: ClimminmaxEditField
        function ClimminmaxEditFieldValueChanged(app, event)
            [app.ClimminmaxEditField.Value] = Utility_SimpleCheckInputs(app.ClimminmaxEditField.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,1))),0,1);

            value = app.ClimminmaxEditField.Value;
            indicesep = find(value == ',');
            climcostumTF(1,1) = str2double(value(1:indicesep-1));
            climcostumTF(1,2) = str2double(value(indicesep+1:end));

            app.UIAxes.CLim = climcostumTF;
        end

        % Button pushed function: AutoClimButton
        function AutoClimButtonPushed(app, event)
            if ~isempty(app.climsTF)
                app.UIAxes.CLim = app.climsTF;
            end
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.TFAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.TFAnalysisUIFigure,fullfile(path,file)) 
        end

        % Value changed function: ChannelSelectionDropDown
        function ChannelSelectionDropDownValueChanged(app, event)
            if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                [app.ChannelSelectionDropDown.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionDropDown.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,1))),1,0);
            else
                [app.ChannelSelectionDropDown.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionDropDown.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,1))),1,0);
            end

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.EventRelatedWindow.Mainapp.Data,app.WaveletTypeDropDown.Value,app.ChannelSelectionDropDown.Value,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                end
            else
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                end
            end
        end

        % Value changed function: IntertrialPhaseClusteringCheckBox
        function IntertrialPhaseClusteringCheckBoxValueChanged(app, event)
            app.TimeFrequencyCheckBox.Value = 0;
            app.TFInfos.PlottedData = "ITPC";

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.EventRelatedWindow.Mainapp.Data,app.WaveletTypeDropDown.Value,app.ChannelSelectionDropDown.Value,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                end
            else
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                end
            end
        end

        % Value changed function: TimeFrequencyCheckBox
        function TimeFrequencyCheckBoxValueChanged(app, event)
            app.IntertrialPhaseClusteringCheckBox.Value = 0;
            app.TFInfos.PlottedData = "TF";

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.EventRelatedWindow.Mainapp.Data,app.WaveletTypeDropDown.Value,app.ChannelSelectionDropDown.Value,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                end
            else
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                end
            end
        end

        % Value changed function: PhaseindependentCheckBox
        function PhaseindependentCheckBoxValueChanged(app, event)
           app.PhaselockedCheckBox.Value = 0;
           app.NonphaselockedCheckBox.Value = 0;
            app.TFInfos.Addons = "Total";
            
            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.EventRelatedWindow.Mainapp.Data,app.WaveletTypeDropDown.Value,app.ChannelSelectionDropDown.Value,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                end
            else
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                end
            end
        end

        % Value changed function: PhaselockedCheckBox
        function PhaselockedCheckBoxValueChanged(app, event)
            app.PhaseindependentCheckBox.Value = 0;
            app.NonphaselockedCheckBox.Value = 0;
            app.TFInfos.Addons = "PhaseLocked";

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.EventRelatedWindow.Mainapp.Data,app.WaveletTypeDropDown.Value,app.ChannelSelectionDropDown.Value,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                end
            else
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                end
            end
        end

        % Value changed function: NonphaselockedCheckBox
        function NonphaselockedCheckBoxValueChanged(app, event)
            app.PhaselockedCheckBox.Value = 0;
            app.PhaseindependentCheckBox.Value = 0;
            app.TFInfos.Addons = "NonPhaseLocked";

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.EventRelatedWindow.Mainapp.Data,app.WaveletTypeDropDown.Value,app.ChannelSelectionDropDown.Value,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                end
            else
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                end
            end
        end

        % Value changed function: FrequencyRangeminmaxstepsEditField
        function FrequencyRangeminmaxstepsEditFieldValueChanged(app, event)

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.EventRelatedWindow.Mainapp.Data,app.WaveletTypeDropDown.Value,app.ChannelSelectionDropDown.Value,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                end
            else
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                end
            end
        end

        % Value changed function: CycleWidthfromto23EditField
        function CycleWidthfromto23EditFieldValueChanged(app, event)
            [app.CycleWidthfromto23EditField.Value] = Utility_SimpleCheckInputs(app.CycleWidthfromto23EditField.Value,"Two",'5,9',0,0);

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.EventRelatedWindow.Mainapp.Data,app.WaveletTypeDropDown.Value,app.ChannelSelectionDropDown.Value,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                end
            else
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                end
            end
        end

        % Value changed function: WaveletTypeDropDown
        function WaveletTypeDropDownValueChanged(app, event)
            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.EventRelatedWindow.Mainapp.Data,app.WaveletTypeDropDown.Value,app.ChannelSelectionDropDown.Value,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                end
            else
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                end
            end
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            value = app.DataTypeDropDown.Value;

            if strcmp(value,'Raw Event Data')
                app.EventNumberSelectionEditField.Value = strcat("1,",num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2)));
            elseif strcmp(value,'Preprocessed Event Data')
                app.EventNumberSelectionEditField.Value = strcat("1,",num2str(size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,2)));
            end

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.EventRelatedWindow.Mainapp.Data,app.WaveletTypeDropDown.Value,app.ChannelSelectionDropDown.Value,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                end
            else
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                end
            end
        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            app.TwoORThreeD = "TwoD";

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Data')
                app.EventNumberSelectionEditField.Value = strcat("1,",num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2)));
            elseif strcmp(app.DataTypeDropDown.Value,'Preprocessed Event Data')
                app.EventNumberSelectionEditField.Value = strcat("1,",num2str(size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,2)));
            end

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.EventRelatedWindow.Mainapp.Data,app.WaveletTypeDropDown.Value,app.ChannelSelectionDropDown.Value,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                end
            else
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                end
            end
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;
            
            if app.TimeFrequencyCheckBox.Value == 1
                AddonName = "Time Frequency Power";
            elseif app.IntertrialPhaseClusteringCheckBox.Value == 1
                AddonName = "Intertrial Phase Clustering";
            end

            if app.PhaseindependentCheckBox.Value == 1
                AddonName = strcat("Phase Independent ",AddonName);
            elseif app.PhaselockedCheckBox.Value == 1
                AddonName = strcat("Phase Locked ",AddonName);
            elseif app.NonphaselockedCheckBox.Value == 1
                AddonName = strcat("Non Phase Locked ",AddonName);
            end

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".mat",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,AddonName);
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
           TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;
            
            if app.TimeFrequencyCheckBox.Value == 1
                AddonName = "Time Frequency Power";
            elseif app.IntertrialPhaseClusteringCheckBox.Value == 1
                AddonName = "Intertrial Phase Clustering";
            end

            if app.PhaseindependentCheckBox.Value == 1
                AddonName = strcat("Phase Independent ",AddonName);
            elseif app.PhaselockedCheckBox.Value == 1
                AddonName = strcat("Phase Locked ",AddonName);
            elseif app.NonphaselockedCheckBox.Value == 1
                AddonName = strcat("Non Phase Locked ",AddonName);
            end

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".txt",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,AddonName);
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;
            
            if app.TimeFrequencyCheckBox.Value == 1
                AddonName = "Time Frequency Power";
            elseif app.IntertrialPhaseClusteringCheckBox.Value == 1
                AddonName = "Intertrial Phase Clustering";
            end

            if app.PhaseindependentCheckBox.Value == 1
                AddonName = strcat("Phase Independent ",AddonName);
            elseif app.PhaselockedCheckBox.Value == 1
                AddonName = strcat("Phase Locked ",AddonName);
            elseif app.NonphaselockedCheckBox.Value == 1
                AddonName = strcat("Non Phase Locked ",AddonName);
            end

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".csv",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,AddonName);
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";
            
            app.TwoORThreeD = "ThreeD";

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Data')
                app.EventNumberSelectionEditField.Value = strcat("1,",num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2)));
            elseif strcmp(app.DataTypeDropDown.Value,'Preprocessed Event Data')
                app.EventNumberSelectionEditField.Value = strcat("1,",num2str(size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,2)));
            end

            [~,DataChannelSelected,EventNrRange,~,TF] = Event_Module_Organize_TF_Window_Inputs(app.EventRelatedWindow.Mainapp.Data,app.WaveletTypeDropDown.Value,app.ChannelSelectionDropDown.Value,app.EventNumberSelectionEditField.Value,app.FrequencyRangeminmaxstepsEditField.Value,app.CycleWidthfromto23EditField.Value,[],[]);

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')                
                if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                    [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                end
            else
                if isfield(app.EventRelatedWindow.Mainapp.Data.Info,"DownsampleFactor")
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                else
                    if strcmp(app.DataTypeDropDown.Value,"Raw Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    elseif strcmp(app.DataTypeDropDown.Value,"Preprocessed Event Data")
                        [app.climsTF,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Time_Frequency_Main(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,app.UIAxes,app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate,DataChannelSelected,EventNrRange,app.TFInfos.Timearoundevent,TF,app.TFInfos.PlottedData,app.TFInfos.Addons,app.WaveletTypeDropDown.Value,app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TFAnalysisUIFigure = uifigure('Visible', 'off');
            app.TFAnalysisUIFigure.Name = 'Time Frequency Power Window';
            app.TFAnalysisUIFigure.Icon = 'Logo.png';
            app.SnapshotMenu = uimenu(app.TFAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu = uimenu(app.TFAnalysisUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.ExportDataasMenu = uimenu(app.TFAnalysisUIFigure);
            app.ExportDataasMenu.Text = '   Export Data as   ';
            app.matMenu = uimenu(app.ExportDataasMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataasMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ExportDataasMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.UIAxes = uiaxes(app.TFAnalysisUIFigure);
            app.TimeFrequencyPowerWindowLabel = uilabel(app.TFAnalysisUIFigure);
            app.TimeFrequencyPowerWindowLabel.Text = 'Time Frequency Power Window';
            app.Image = uiimage(app.TFAnalysisUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.TFAnalysisUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.EventandDataChannelSelectionPanel = uipanel(app.TFAnalysisUIFigure);
            app.EventandDataChannelSelectionPanel.Title = 'Event and Data Channel Selection';
            app.EventNumberSelectionEditFieldLabel = uilabel(app.EventandDataChannelSelectionPanel);
            app.EventNumberSelectionEditFieldLabel.Text = {'Event Number'; 'Selection'};
            app.EventNumberSelectionEditField = uieditfield(app.EventandDataChannelSelectionPanel, 'text');
            app.EventNumberSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @EventNumberSelectionEditFieldValueChanged, true);
            app.ChannelLabel = uilabel(app.EventandDataChannelSelectionPanel);
            app.ChannelLabel.Text = {'Channel Selection'; '[from,to]'};
            app.ChannelSelectionDropDown = uieditfield(app.EventandDataChannelSelectionPanel, 'text');
            app.ChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDownValueChanged, true);
            app.DataTypeDropDownLabel = uilabel(app.EventandDataChannelSelectionPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.EventandDataChannelSelectionPanel);
            app.DataTypeDropDown.Items = {''};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = '';
            app.CloseButton = uibutton(app.TFAnalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.WaveletAnalysisParameterPanel_2 = uipanel(app.TFAnalysisUIFigure);
            app.WaveletAnalysisParameterPanel_2.Title = 'Wavelet Analysis Parameter';
            app.CycleWidthfromtoEditFieldLabel = uilabel(app.WaveletAnalysisParameterPanel_2);
            app.CycleWidthfromtoEditFieldLabel.Text = {'Cycle Width'; '(from , to)'};
            app.CycleWidthfromto23EditField = uieditfield(app.WaveletAnalysisParameterPanel_2, 'text');
            app.CycleWidthfromto23EditField.ValueChangedFcn = createCallbackFcn(app, @CycleWidthfromto23EditFieldValueChanged, true);
            app.CycleWidthfromto23EditField.Value = '5,9';
            app.FrequRangeminmaxstepsEditFieldLabel = uilabel(app.WaveletAnalysisParameterPanel_2);
            app.FrequRangeminmaxstepsEditFieldLabel.Text = {'Frequ. Range'; '(min,max,steps)'};
            app.FrequencyRangeminmaxstepsEditField = uieditfield(app.WaveletAnalysisParameterPanel_2, 'text');
            app.FrequencyRangeminmaxstepsEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyRangeminmaxstepsEditFieldValueChanged, true);
            app.FrequencyRangeminmaxstepsEditField.Value = '10,80,120';
            app.AutoClimButton = uibutton(app.WaveletAnalysisParameterPanel_2, 'push');
            app.AutoClimButton.ButtonPushedFcn = createCallbackFcn(app, @AutoClimButtonPushed, true);
            app.AutoClimButton.Text = 'Auto Clim';
            app.ClimminmaxLabel = uilabel(app.WaveletAnalysisParameterPanel_2);
            app.ClimminmaxLabel.Text = {'Clim '; '[min max]'};
            app.ClimminmaxEditField = uieditfield(app.WaveletAnalysisParameterPanel_2, 'text');
            app.ClimminmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ClimminmaxEditFieldValueChanged, true);
            app.Panel = uipanel(app.TFAnalysisUIFigure);
            app.TimeFrequencyCheckBox = uicheckbox(app.Panel);
            app.TimeFrequencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeFrequencyCheckBoxValueChanged, true);
            app.TimeFrequencyCheckBox.Text = 'Time Frequency';
            app.TimeFrequencyCheckBox.Value = true;
            app.IntertrialPhaseClusteringCheckBox = uicheckbox(app.Panel);
            app.IntertrialPhaseClusteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @IntertrialPhaseClusteringCheckBoxValueChanged, true);
            app.IntertrialPhaseClusteringCheckBox.Text = {'Intertrial Phase'; 'Clustering'};
            app.PlottedDataLabel = uilabel(app.Panel);
            app.PlottedDataLabel.Text = 'Plotted Data';
            app.NonphaselockedCheckBox = uicheckbox(app.Panel);
            app.NonphaselockedCheckBox.ValueChangedFcn = createCallbackFcn(app, @NonphaselockedCheckBoxValueChanged, true);
            app.NonphaselockedCheckBox.Text = 'Non-phase locked';
            app.PhaselockedCheckBox = uicheckbox(app.Panel);
            app.PhaselockedCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhaselockedCheckBoxValueChanged, true);
            app.PhaselockedCheckBox.Text = 'Phase locked';
            app.AddonsLabel = uilabel(app.Panel);
            app.AddonsLabel.Text = 'Addons';
            app.PhaseindependentCheckBox = uicheckbox(app.Panel);
            app.PhaseindependentCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhaseindependentCheckBoxValueChanged, true);
            app.PhaseindependentCheckBox.Text = 'Phase independent';
            app.PhaseindependentCheckBox.Value = true;
            app.WaveletTypeDropDownLabel = uilabel(app.Panel);
            app.WaveletTypeDropDownLabel.Text = 'Wavelet Type';
            app.WaveletTypeDropDown = uidropdown(app.Panel);
            app.WaveletTypeDropDown.Items = {'Moorlet Wavelets'};
            app.WaveletTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveletTypeDropDownValueChanged, true);
            app.WaveletTypeDropDown.Value = 'Moorlet Wavelets';
            % Show the figure after all components are created
            app.TFAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Time_Frequency_Power_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TFAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TFAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TFAnalysisUIFigure)
        end
    end
end