classdef Show_Event_Channel_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Show_Input_Channel_WindowUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CloseButton                    matlab.ui.control.Button
        NeuromodToolboxLabel           matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ShowAllInputChannelLabel       matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        SelectInputChannelNumberLabel  matlab.ui.control.Label
        FileTypeDropDown_2             matlab.ui.control.DropDown
        SelectInputChannelTypeLabel    matlab.ui.control.Label
        FileTypeDropDown               matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        EWapp = [];
        ChannelWithStuff = [];
        EventChannelNames = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, EWapp)
            % Moce this window in the middle of thescreen
            movegui(app.Show_Input_Channel_WindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.EWapp = EWapp;
            
            ChannelPlot = [];

            %% Determine for which kind of event channels data is present. First one that has data is plotted

            if isempty(app.EWapp.EventInfo.DIChannel)
                ChannelPlot = 0;
            else
                Extract_Events_Module_Show_ChannelPlots("Digital Inputs",app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial");
                ChannelPlot = 1;
                app.ChannelWithStuff = "Digital Inputs";
                return;
            end
            if isempty(app.EWapp.EventInfo.ADCChannel)
                ChannelPlot = 0;
            else
                Extract_Events_Module_Show_ChannelPlots("Analog Input",app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial");
                ChannelPlot = 1;
                app.ChannelWithStuff = "Analog Input";
                return;
            end
            if isempty(app.EWapp.EventInfo.AUXChannel)
                ChannelPlot = 0;
            else
                Extract_Events_Module_Show_ChannelPlots("AUX Inputs",app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial");
                ChannelPlot = 1;
                app.ChannelWithStuff = "AUX Inputs";
                return;
            end
            

            if ChannelPlot == 0 || isempty(ChannelPlot)
                f = msgbox("No Input Channel found in selected folder");
                app.TextArea.Value = "No Input Channel found in selected folder";
                delete(app);
            end
        end

        % Value changed function: FileTypeDropDown
        function FileTypeDropDownValueChanged(app, event)
           value = app.FileTypeDropDown.Value;

            if strcmp(app.Mainapp.Data.Info.RecordingType,"IntanDat") 
                if strcmp(app.FileTypeDropDown.Value,"Digital Inputs")
                    if isempty(app.EWapp.EventInfo.DIChannel)
                        f = msgbox("No Digital Input Channel found in selected folder");
                        app.FileTypeDropDown.Value = app.ChannelWithStuff;
                        app.FileTypeDropDown_2.Items = {};
                        if strcmp(app.ChannelWithStuff,"Digital Inputs")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Digital;
                        elseif strcmp(app.ChannelWithStuff,"Analog Input")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Analog;
                        elseif strcmp(app.ChannelWithStuff,"AUX Inputs")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Aux;
                        end
                        return;

                    else
                        app.FileTypeDropDown_2.Items = app.EventChannelNames.Digital;
                    end
              
                elseif strcmp(app.FileTypeDropDown.Value,"Analog Input")
                    if isempty(app.EWapp.EventInfo.ADCChannel)
                        f = msgbox("No Analog Input Channel found in selected folder");
                        app.FileTypeDropDown.Value = app.ChannelWithStuff;
                        app.FileTypeDropDown_2.Items = {};
                        if strcmp(app.ChannelWithStuff,"Digital Inputs")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Digital;
                        elseif strcmp(app.ChannelWithStuff,"Analog Input")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Analog;
                        elseif strcmp(app.ChannelWithStuff,"AUX Inputs")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Aux;
                        end
                        return;

                    else
                        app.FileTypeDropDown_2.Items = app.EventChannelNames.Analog;
                    end
            
                elseif strcmp(app.FileTypeDropDown.Value,"AUX Inputs")
                    if isempty(app.EWapp.EventInfo.AUXChannel) 
                        f = msgbox("No Aux Input Channel found in selected folder");
                        app.FileTypeDropDown.Value = app.ChannelWithStuff;
                        app.FileTypeDropDown_2.Items = {};
                        if strcmp(app.ChannelWithStuff,"Digital Inputs")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Digital;
                        elseif strcmp(app.ChannelWithStuff,"Analog Input")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Analog;
                        elseif strcmp(app.ChannelWithStuff,"AUX Inputs")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Aux;
                        end
                        return;

                    else
                        app.FileTypeDropDown_2.Items = app.EventChannelNames.Aux;
                    end
                end

            elseif strcmp(app.Mainapp.Data.Info.RecordingType,"IntanRHD") 
                if strcmp(app.FileTypeDropDown.Value,"Digital Inputs")
                    if app.EWapp.EventInfo.DIChannel == 0 
                        f = msgbox("No Digital Input Channel found in selected folder");
                        app.FileTypeDropDown.Value = app.ChannelWithStuff;
                        app.FileTypeDropDown_2.Items = {};
                        if strcmp(app.ChannelWithStuff,"Digital Inputs")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Digital;
                        elseif strcmp(app.ChannelWithStuff,"Analog Input")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Analog;
                        elseif strcmp(app.ChannelWithStuff,"AUX Inputs")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Aux;
                        end
                        return;
                    
                    else
                        app.FileTypeDropDown_2.Items = app.EventChannelNames.Digital;
                    end
              
                elseif strcmp(app.FileTypeDropDown.Value,"Analog Input")
                    if app.EWapp.EventInfo.ADCChannel == 0 
                        f = msgbox("No Analog Input Channel found in selected folder");
                        app.FileTypeDropDown.Value = app.ChannelWithStuff;
                        app.FileTypeDropDown_2.Items = {};
                        if strcmp(app.ChannelWithStuff,"Digital Inputs")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Digital;
                        elseif strcmp(app.ChannelWithStuff,"Analog Input")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Analog;
                        elseif strcmp(app.ChannelWithStuff,"AUX Inputs")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Aux;
                        end
                        return;

                    else
                        app.FileTypeDropDown_2.Items = app.EventChannelNames.Analog;

                    end
            
                elseif strcmp(app.FileTypeDropDown.Value,"AUX Inputs")
                    if app.EWapp.EventInfo.AUXChannel == 0 
                        f = msgbox("No Aux Input Channel found in selected folder");
                        app.FileTypeDropDown.Value = app.ChannelWithStuff;
                        app.FileTypeDropDown_2.Items = {};
                        if strcmp(app.ChannelWithStuff,"Digital Inputs")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Digital;
                        elseif strcmp(app.ChannelWithStuff,"Analog Input")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Analog;
                        elseif strcmp(app.ChannelWithStuff,"AUX Inputs")
                            app.FileTypeDropDown_2.Items = app.EventChannelNames.Aux;
                        end
                        return;

                    
                    else
                        app.FileTypeDropDown_2.Items = app.EventChannelNames.Aux;
                    end
                end
            end
       
            Extract_Events_Module_Show_ChannelPlots(value,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Initial");
        end

        % Value changed function: FileTypeDropDown_2
        function FileTypeDropDown_2ValueChanged(app, event)
            
            
            Extract_Events_Module_Show_ChannelPlots(app.FileTypeDropDown.Value,app.EWapp.Path,app,app.EWapp.RHDAllChannelData,"Subsequent");   
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Show_Input_Channel_WindowUIFigure = uifigure('Visible', 'off');
            app.Show_Input_Channel_WindowUIFigure.Name = 'Show_Input_Channel_Window';
            app.Show_Input_Channel_WindowUIFigure.Icon = 'Logo.png';
            app.GridLayout = uigridlayout(app.Show_Input_Channel_WindowUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.FileTypeDropDown = uidropdown(app.GridLayout2);
            app.FileTypeDropDown.Items = {'Digital Inputs', 'Analog Input', 'AUX Inputs'};
            app.FileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDownValueChanged, true);
            app.FileTypeDropDown.Value = 'Digital Inputs';
            app.SelectInputChannelTypeLabel = uilabel(app.GridLayout2);
            app.SelectInputChannelTypeLabel.Text = {'Select Input Channel'; ' Type'};
            app.FileTypeDropDown_2 = uidropdown(app.GridLayout2);
            app.FileTypeDropDown_2.Items = {};
            app.FileTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDown_2ValueChanged, true);
            app.FileTypeDropDown_2.Value = {};
            app.SelectInputChannelNumberLabel = uilabel(app.GridLayout2);
            app.SelectInputChannelNumberLabel.Text = {'Select Input Channel'; ' Number'};
            app.ShowAllInputChannelLabel = uilabel(app.GridLayout);
            app.ShowAllInputChannelLabel.Text = 'Show All Input Channel';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.GridLayout);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.Show_Input_Channel_WindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Show_Event_Channel_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Show_Input_Channel_WindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Show_Input_Channel_WindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Show_Input_Channel_WindowUIFigure)
        end
    end
end