classdef Preprocessing_Events_Channel_Rejection_and_Interpolation_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelRejectionWindowUIFigure  matlab.ui.Figure
        ChannelSettingsLabel            matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        SaveasnewDatasetButton          matlab.ui.control.Button
        ChannelSettingsPanel            matlab.ui.container.Panel
        Slider                          matlab.ui.control.Slider
        DataLineSpacingLabel            matlab.ui.control.Label
        DataToExtractFromDropDown       matlab.ui.control.DropDown
        DataToExtractFromDropDownLabel  matlab.ui.control.Label
        EventChannelSelectionDropDown   matlab.ui.control.DropDown
        EventChannelSelectionDropDownLabel  matlab.ui.control.Label
        RejectChannelFormat11or110EditField  matlab.ui.control.EditField
        InterpolateChannelFormat11or110emptyfornonLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        ChangeChannelSpacingLimitsMenu  matlab.ui.container.Menu
    end

    
    properties (Access = public)
        % Add a property to store the names of the vectors
        TextHandle = []; % Store the handle to the displayed text
        EventTime = []; % Store the handle to the displayed text
        Mainapp = [];
        EventsRelatedApp = [];
    end
        
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, EventsRelatedApp)
            movegui(app.ChannelRejectionWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.EventsRelatedApp = EventsRelatedApp;
            
            app = Utility_Change_Light_Dark_Mode(app,"EventChannelRejection");

            %% Fill GUI fields

            app.Slider.Limits = app.EventsRelatedApp.Mainapp.Slider.Limits;
            app.Slider.Value = app.EventsRelatedApp.Mainapp.Slider.Value;

            EventRelatedDataTimeRange = [];
                 
            spaceindicie = find(app.Mainapp.Data.Info.EventRelatedDataTimeRange == ' ');
            EventRelatedDataTimeRange(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)));
            EventRelatedDataTimeRange(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')
                app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.NativeSamplingRate:EventRelatedDataTimeRange(2);
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.DownsampledSampleRate:EventRelatedDataTimeRange(2);
                else
                    app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.NativeSamplingRate:EventRelatedDataTimeRange(2);
                end
            end

            app.UIAxes.FontSize = 10;
            app.UIAxes.YTick = [];
            app.UIAxes.YLabel = [];
            xlabel(app.UIAxes, 'Time [s]');

            %% Plot with standard values on startup
            %% Get Timewindow and trial information from GUI
            try
                RejectChannel = eval(app.RejectChannelFormat11or110EditField.Value);
            catch
                RejectChannel = [];
            end

            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            else
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            end
        end

        % Button pushed function: SaveasnewDatasetButton
        function SaveasnewDatasetButtonPushed(app, event)
            %% Check ChannelSelection

            %% Get Timewindow and trial information from GUI
            try
                RejectChannel = eval(app.RejectChannelFormat11or110EditField.Value);
            catch
                RejectChannel = [];
            end

            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [app.Mainapp.Data.PreprocessedEventRelatedData] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            else
                [app.Mainapp.Data.PreprocessedEventRelatedData] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            end

            Trials = [1,size(app.Mainapp.Data.PreprocessedEventRelatedData,2)];
            
            [app.Mainapp.Data] = Preprocessing_Events_Add_Preprocessing_Info(app.Mainapp.Data,'Channel Rejection',RejectChannel,Trials,[]);

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Preprocessing_Events_PopulateInfoText(app.EventsRelatedApp.TextArea,app.Mainapp.Data);

            msgbox("Changes were succesfully applied to the dataset.");
        end

        % Value changed function: RejectChannelFormat11or110EditField
        function RejectChannelFormat11or110EditFieldValueChanged(app, event)
            if isempty(app.RejectChannelFormat11or110EditField.Value)
                RejectChannel = [];
            else
                try
                    RejectChannel = eval(app.RejectChannelFormat11or110EditField.Value);
                catch
                    a = str2double(strsplit(app.RejectChannelFormat11or110EditField.Value,','));
                    if length(a) == 2
                        app.RejectChannelFormat11or110EditField.Value = strcat('[',app.RejectChannelFormat11or110EditField.Value,']');
                        RejectChannel = eval(app.RejectChannelFormat11or110EditField.Value);
                    else
                        msgbox("Format Error! Please use Matlab expressions.")
                        RejectChannel = [];
                    end
                    
                end
            end
            app.UIAxes.NextPlot = "replace";
            cla(app.UIAxes);
            app.UIAxes.NextPlot = "add";
             
            %% Plot with standard values on startup
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            else
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            end
        end

        % Callback function
        function ElectrodeofInterestDropDownValueChanged(app, event)
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            %% Check ChannelSelection
            try
                RejectChannel = eval(app.RejectChannelFormat11or110EditField.Value);
            catch
                RejectChannel = [];
            end
                        
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            else
                [~] = Preprocessing_Events_Channel_Rejection(app.Mainapp.Data,app.Mainapp.Data.EventRelatedData,app.EventTime,RejectChannel,app.Slider.Value,app.UIAxes,"All",app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
            end
        end

        % Menu selected function: ChangeChannelSpacingLimitsMenu
        function ChangeChannelSpacingLimitsMenuSelected(app, event)
            Channel_Spacing_Selection(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelRejectionWindowUIFigure = uifigure('Visible', 'off');
            app.ChannelRejectionWindowUIFigure.Name = 'Channel Rejection Window';
            app.ChannelRejectionWindowUIFigure.Icon = 'Logo.png';
            app.UIAxes = uiaxes(app.ChannelRejectionWindowUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.ChannelSettingsPanel = uipanel(app.ChannelRejectionWindowUIFigure);
            app.ChannelSettingsPanel.Title = 'Channel Settings';
            app.InterpolateChannelFormat11or110emptyfornonLabel = uilabel(app.ChannelSettingsPanel);
            app.InterpolateChannelFormat11or110emptyfornonLabel.Text = {'Interpolate Channel,'; '(Matlab expressions)'; '[empty for non]'};
            app.RejectChannelFormat11or110EditField = uieditfield(app.ChannelSettingsPanel, 'text');
            app.RejectChannelFormat11or110EditField.ValueChangedFcn = createCallbackFcn(app, @RejectChannelFormat11or110EditFieldValueChanged, true);
            app.EventChannelSelectionDropDownLabel = uilabel(app.ChannelSettingsPanel);
            app.EventChannelSelectionDropDownLabel.Text = 'Event Channel Selection';
            app.EventChannelSelectionDropDown = uidropdown(app.ChannelSettingsPanel);
            app.EventChannelSelectionDropDown.Items = {};
            app.EventChannelSelectionDropDown.Value = {};
            app.DataToExtractFromDropDownLabel = uilabel(app.ChannelSettingsPanel);
            app.DataToExtractFromDropDownLabel.Text = 'Data To Extract From';
            app.DataToExtractFromDropDown = uidropdown(app.ChannelSettingsPanel);
            app.DataToExtractFromDropDown.Items = {};
            app.DataToExtractFromDropDown.Value = {};
            app.DataLineSpacingLabel = uilabel(app.ChannelSettingsPanel);
            app.DataLineSpacingLabel.Text = {'Data Line'; 'Spacing'};
            app.Slider = uislider(app.ChannelSettingsPanel);
            app.Slider.Limits = [0.001 0.1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 0.001;
            app.SaveasnewDatasetButton = uibutton(app.ChannelRejectionWindowUIFigure, 'push');
            app.SaveasnewDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasnewDatasetButtonPushed, true);
            app.SaveasnewDatasetButton.Text = {'Save as new'; 'Dataset'};
            app.CloseButton = uibutton(app.ChannelRejectionWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ChannelSettingsLabel = uilabel(app.ChannelRejectionWindowUIFigure);
            app.ChannelSettingsLabel.Text = 'Channel Settings';
            app.ContextMenu = uicontextmenu(app.ChannelRejectionWindowUIFigure);
            app.ChangeChannelSpacingLimitsMenu = uimenu(app.ContextMenu);
            app.ChangeChannelSpacingLimitsMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeChannelSpacingLimitsMenuSelected, true);
            app.ChangeChannelSpacingLimitsMenu.Text = 'Change Channel Spacing Limits';
            % Assign app.ContextMenu
            app.Slider.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.ChannelRejectionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing_Events_Channel_Rejection_and_Interpolation_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChannelRejectionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ChannelRejectionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelRejectionWindowUIFigure)
        end
    end
end