classdef Spike_Detection_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeDetectionWindowUIFigure    matlab.ui.Figure
        InformationLabel                matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SpikeDetectionWindowLabel       matlab.ui.control.Label
        SpikeDetectionPanel             matlab.ui.container.Panel
        FilterVerticalSpikeArtefactsCheckBox  matlab.ui.control.CheckBox
        StartSpikeDetectionButton       matlab.ui.control.Button
        MinDepthofArtefactmEditField    matlab.ui.control.EditField
        MinDepthofArtefactmEditFieldLabel  matlab.ui.control.Label
        VerticalSpikeOffsetToleranceSamplesEditField  matlab.ui.control.EditField
        VerticalSpikeOffsetToleranceSamplesEditFieldLabel  matlab.ui.control.Label
        MeanDropDown                    matlab.ui.control.DropDown
        MeanandStdDropDownLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.EditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        DetectionMethodDropDown         matlab.ui.control.DropDown
        DetectionMethodDropDownLabel    matlab.ui.control.Label
        Wave_clus3SpikeSortingPanel     matlab.ui.container.Panel
        LoadExistingSpikeSortingButton  matlab.ui.control.Button
        ChangeSpikeSortingSettingsButton  matlab.ui.control.Button
        ManuallyselectFolderwithSortingResultsButton  matlab.ui.control.Button
        CreateNewSpikeSortingButton     matlab.ui.control.Button
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        SpikeSortingPath = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SpikeDetectionWindowUIFigure, 'center');

            app.Mainapp = Mainapp;

            %% Check if high pass filtered data is available. If not, display error message and return
            if ~isfield(app.Mainapp.Data,'Preprocessed')
                f = msgbox("Error: No preprocessed Data detected. Spike Detection on Raw Data wont work! High pass filter and open this window again!");
                delete(app);
                return;
            else
                if ~isfield(app.Mainapp.Data.Info,'FilterMethod')
                    f = msgbox("Error: No high pass filtered Data detected. Spike Detection on Raw Data wont work! High pass filter and open this window again!");
                    delete(app);
                    return;
                else
                    if ~strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                        f = msgbox("Error: No high pass filtered Data detected. Spike Detection on Raw Data wont work! High pass filter and open this window again!");
                        delete(app);
                        return;
                    end
                end
            end

            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,"Internal")
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end

            app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "off";
            app.MinDepthofArtefactmEditField.Enable = "off";

            % Check if spike sorting was already saved
            % Check if the folder exists
            app.SpikeSortingPath = strcat(app.Mainapp.Data.Info.Data_Path,'\Wave_Clus');
            
            cluster_class = [];

            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                app.TextArea.Value = textboxx;
            else
                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath);

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                else
                    textboxx = [strcat("Path: ",app.SpikeSortingPath);"";"does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder"];
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                    app.TextArea.Value = textboxx;
                    return;
                end
                
                if ~isempty(cluster_class)
                    numclu = length(unique(cluster_class(:,1)));
                else
                    numclu = 1;
                end

                textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                
                % Initialize a string array to store the output
                outputText = "";
                
                % Get all field names from the structure
                fields = fieldnames(par);

                % Loop through each field and concatenate the field name and value
                for i = 1:numel(fields)
                    fieldName = fields{i};
                    fieldValue = par.(fieldName);
                    
                    % Convert the field value to a string if it is not already
                    if isnumeric(fieldValue)
                        fieldValue = num2str(fieldValue);
                    elseif ischar(fieldValue)
                        fieldValue = string(fieldValue);
                    end
                    
                    % Concatenate the field name and value
                    outputText = outputText + fieldName + ": " + fieldValue + newline;
                end
                
                % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                textboxx = [textboxx;outputText];
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx;outputText];

                app.TextArea.Value = textboxx;
                
            end
           
        end

        % Button pushed function: StartSpikeDetectionButton
        function StartSpikeDetectionButtonPushed(app, event)
            disp(strcat("Extracting Spikes. Please wait until the progress bar closes"));

            %% Spike Detection
            [app.Mainapp.Data,~] = Spike_Module_Spike_Detection(app.Mainapp.Data,app.DetectionMethodDropDown.Value,app.MeanDropDown.Value,str2double(app.ThresholdEditField.Value),app.FilterVerticalSpikeArtefactsCheckBox.Value,app.VerticalSpikeOffsetToleranceSamplesEditField.Value,app.MinDepthofArtefactmEditField.Value);

            %% Aftmeramth: Resetting Main window Plots and plot data again
            disp(strcat("Spike detection finished. They can now be visualized and analyzed in the main window"));

            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes.NextPlot = "add"; 
            app.Mainapp.UIAxes_2.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes_2,0,0);
            app.Mainapp.UIAxes_2.NextPlot = "add"; 

            %Take care of potentially changed backgroundcolor
            app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
            app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;

            if ~isfield(app.Mainapp.Data,'Spikes')
                app.Mainapp.SpikeDataCheckBox_2.Value = 0;
                app.Mainapp.SpikeDataCheckBox_2.Enable = "off";
                app.Mainapp.Plotspikes = "Non";
            else
                app.Mainapp.SpikeDataCheckBox_2.Value = 0;
                app.Mainapp.SpikeDataCheckBox_2.Enable = "on";
                app.Mainapp.Plotspikes = "Non";
            end

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            %% Fill GUI textbox with new data
            % Check if spike sorting was already saved
            % Check if the folder exists
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,"Internal")
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end

            app.SpikeSortingPath = strcat(app.Mainapp.Data.Info.Data_Path,'\Wave_Clus');
            
            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                app.TextArea.Value = textboxx;
            else
                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath);

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                else
                    textboxx = [strcat("Path: ",app.SpikeSortingPath);"";"does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder"];
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                    app.TextArea.Value = textboxx;
                    return;
                end

                numclu = length(unique(cluster_class(:,1)));

                textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                
                % Initialize a string array to store the output
                outputText = "";
                
                % Get all field names from the structure
                fields = fieldnames(par);

                % Loop through each field and concatenate the field name and value
                for i = 1:numel(fields)
                    fieldName = fields{i};
                    fieldValue = par.(fieldName);
                    
                    % Convert the field value to a string if it is not already
                    if isnumeric(fieldValue)
                        fieldValue = num2str(fieldValue);
                    elseif ischar(fieldValue)
                        fieldValue = string(fieldValue);
                    end
                    
                    % Concatenate the field name and value
                    outputText = outputText + fieldName + ": " + fieldValue + newline;
                end
                
                % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                textboxx = [textboxx;outputText];
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx;outputText];

                app.TextArea.Value = textboxx;
                
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: DetectionMethodDropDown
        function DetectionMethodDropDownValueChanged(app, event)
            
        end

        % Value changed function: FilterVerticalSpikeArtefactsCheckBox
        function FilterVerticalSpikeArtefactsCheckBoxValueChanged(app, event)
            value = app.FilterVerticalSpikeArtefactsCheckBox.Value;
            if value == 1
                app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "on";
                app.MinDepthofArtefactmEditField.Enable = "on";
            else
                app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "off";
                app.MinDepthofArtefactmEditField.Enable = "off";
            end
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            [app.ThresholdEditField.Value] = Utility_SimpleCheckInputs(app.ThresholdEditField.Value,"One",'4',0,0);
            
        end

        % Value changed function: 
        % VerticalSpikeOffsetToleranceSamplesEditField
        function VerticalSpikeOffsetToleranceSamplesEditFieldValueChanged(app, event)
            [app.VerticalSpikeOffsetToleranceSamplesEditField.Value] = Utility_SimpleCheckInputs(app.VerticalSpikeOffsetToleranceSamplesEditField.Value,"One",'3',0,0);
            
        end

        % Value changed function: MinDepthofArtefactmEditField
        function MinDepthofArtefactmEditFieldValueChanged(app, event)
            [app.MinDepthofArtefactmEditField.Value] = Utility_SimpleCheckInputs(app.MinDepthofArtefactmEditField.Value,"One",'200',0,0);
            
        end

        % Button pushed function: CreateNewSpikeSortingButton
        function CreateNewSpikeSortingButtonPushed(app, event)
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                if isfield(app.Mainapp.Data,'Preprocessed')
                    if isfield(app.Mainapp.Data.Info,'FilterMethod')
                        if strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                            [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,app.SpikeSortingPath,"Clustering");
                        else
                            msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                            return;
                        end
                    else
                        msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                        return;
                    end
                else
                    msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                    return;
                end
            else
                msgbox("No Internal Spike Data found. Please first Start Spike Detection!")
                return;
            end

            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes.NextPlot = "add"; 
            app.Mainapp.UIAxes_2.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes_2,0,0);
            app.Mainapp.UIAxes_2.NextPlot = "add"; 

            %Take care of potentially changed backgroundcolor
            app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
            app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;

            if ~isfield(app.Mainapp.Data,'Spikes')
                app.Mainapp.SpikeDataCheckBox_2.Value = 0;
                app.Mainapp.SpikeDataCheckBox_2.Enable = "off";
                app.Mainapp.Plotspikes = "Non";
            else
                app.Mainapp.SpikeDataCheckBox_2.Value = 0;
                app.Mainapp.SpikeDataCheckBox_2.Enable = "on";
                app.Mainapp.Plotspikes = "Non";
            end

            %% Update Textbox "Recording Information" in Main window with new preprocessing infos
            Utility_Show_Info_Loaded_Data(app.Mainapp);

            %% Fill GUI textbox with new data
            % Check if spike sorting was already saved
            % Check if the folder exists
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end

            app.SpikeSortingPath = strcat(app.Mainapp.Data.Info.Data_Path,'\Wave_Clus');
            
            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                app.TextArea.Value = textboxx;
            else
                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath);

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                else
                    textboxx = [strcat("Path: ",app.SpikeSortingPath);"";"does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder"];
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                    app.TextArea.Value = textboxx;
                    return;
                end

                numclu = length(unique(cluster_class(:,1)));

                textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                
                % Initialize a string array to store the output
                outputText = "";
                
                % Get all field names from the structure
                fields = fieldnames(par);

                % Loop through each field and concatenate the field name and value
                for i = 1:numel(fields)
                    fieldName = fields{i};
                    fieldValue = par.(fieldName);
                    
                    % Convert the field value to a string if it is not already
                    if isnumeric(fieldValue)
                        fieldValue = num2str(fieldValue);
                    elseif ischar(fieldValue)
                        fieldValue = string(fieldValue);
                    end
                    
                    % Concatenate the field name and value
                    outputText = outputText + fieldName + ": " + fieldValue + newline;
                end
                
                % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                textboxx = [textboxx;outputText];
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx;outputText];

                app.TextArea.Value = textboxx;
                
            end
        end

        % Button pushed function: 
        % ManuallyselectFolderwithSortingResultsButton
        function ManuallyselectFolderwithSortingResultsButtonPushed(app, event)
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionThreshold));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end

            folderPath = uigetdir();
            
            if isempty(folderPath)
                return;
            end

            % Check if spike sorting was already saved
            % Check if the folder exists
            app.SpikeSortingPath = strcat(folderPath,'\Wave_Clus');
            
            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                app.TextArea.Value = textboxx;
            else
                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath);

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                else
                    textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                    app.TextArea.Value = textboxx;
                    return;
                end

                numclu = length(unique(cluster_class(:,1)));

                textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                
                % Initialize a string array to store the output
                outputText = "";
                
                % Get all field names from the structure
                fields = fieldnames(par);

                % Loop through each field and concatenate the field name and value
                for i = 1:numel(fields)
                    fieldName = fields{i};
                    fieldValue = par.(fieldName);
                    
                    % Convert the field value to a string if it is not already
                    if isnumeric(fieldValue)
                        fieldValue = num2str(fieldValue);
                    elseif ischar(fieldValue)
                        fieldValue = string(fieldValue);
                    end
                    
                    % Concatenate the field name and value
                    outputText = outputText + fieldName + ": " + fieldValue + newline;
                end
                
                % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                textboxx = [textboxx;outputText];
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx;outputText];

                app.TextArea.Value = textboxx;

            end
        end

        % Button pushed function: ChangeSpikeSortingSettingsButton
        function ChangeSpikeSortingSettingsButtonPushed(app, event)
            set_parameters_ui();
        end

        % Button pushed function: LoadExistingSpikeSortingButton
        function LoadExistingSpikeSortingButtonPushed(app, event)
            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                if isfield(app.Mainapp.Data,'Preprocessed')
                    if isfield(app.Mainapp.Data.Info,'FilterMethod')
                        if strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                            [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,app.SpikeSortingPath,"Loading");
                        else
                            msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                            return;
                        end
                    else
                        msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                        return;
                    end
                else
                    msgbox("No High Pass Filtered Data found! Please first apply a high pass filter to obtain proper waveforms!")
                    return;
                end
            else
                [app.Mainapp.Data] = Spike_Module_Internal_Spike_Sorting(app.Mainapp.Data,app.SpikeSortingPath,"Loading");
            end

            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes.NextPlot = "add"; 
            app.Mainapp.UIAxes_2.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes_2,0,0);
            app.Mainapp.UIAxes_2.NextPlot = "add"; 

            %Take care of potentially changed backgroundcolor
            app.Mainapp.UIAxes.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.MainBackground;
            app.Mainapp.UIAxes_2.Color = app.Mainapp.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            app.Mainapp.UIAxes.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.MainFontSize;
            app.Mainapp.UIAxes_2.FontSize = app.Mainapp.PlotAppearance.MainWindow.Data.TimeFontSize;

            if ~isfield(app.Mainapp.Data,'Spikes')
                app.Mainapp.SpikeDataCheckBox_2.Value = 0;
                app.Mainapp.SpikeDataCheckBox_2.Enable = "off";
                app.Mainapp.Plotspikes = "Non";
            else
                app.Mainapp.SpikeDataCheckBox_2.Value = 0;
                app.Mainapp.SpikeDataCheckBox_2.Enable = "on";
                app.Mainapp.Plotspikes = "Non";
            end

            %% Update Textbox "Recording Information" in Main window with new preprocessing infos
            Utility_Show_Info_Loaded_Data(app.Mainapp);

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

            % Check if spike sorting was already saved
            % Check if the folder exists
            app.SpikeSortingPath = strcat(app.Mainapp.Data.Info.Data_Path,'\Wave_Clus');

            if isfield(app.Mainapp.Data,'Spikes') && strcmp(app.Mainapp.Data.Info.SpikeType,'Internal')
                SpikeText = strcat("Existing Spike Data was found with threshold ",num2str(app.Mainapp.Data.Info.SpikeDetectionNrStd));
            else
                SpikeText = strcat("No Existing Internal Spike Data was found");
            end
            
            %% Fill GUI textbox with new data
            if ~isfolder(app.SpikeSortingPath) % No sorting found
                textboxx = strcat("Path: ",app.SpikeSortingPath," does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder");
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                app.TextArea.Value = textboxx;
            else
                textboxx = strcat("Spike Sorting Data found in: ",app.SpikeSortingPath);

                if isfile(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')))
                    load(strcat(strcat(app.SpikeSortingPath,'\times_spikes.mat')));
                else
                    textboxx = [strcat("Path: ",app.SpikeSortingPath);"";"does not contain spike sorting results folder or path is not available anymore. Eihter start a new sorting or load a different folder"];
                    SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                    textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx];
                    app.TextArea.Value = textboxx;
                    return;
                end

                numclu = length(unique(cluster_class(:,1)));

                textboxx = [textboxx;" ";strcat("Number of Cluster: ",num2str(numclu));""];
                
                % Initialize a string array to store the output
                outputText = "";
                
                % Get all field names from the structure
                fields = fieldnames(par);

                % Loop through each field and concatenate the field name and value
                for i = 1:numel(fields)
                    fieldName = fields{i};
                    fieldValue = par.(fieldName);
                    
                    % Convert the field value to a string if it is not already
                    if isnumeric(fieldValue)
                        fieldValue = num2str(fieldValue);
                    elseif ischar(fieldValue)
                        fieldValue = string(fieldValue);
                    end
                    
                    % Concatenate the field name and value
                    outputText = outputText + fieldName + ": " + fieldValue + newline;
                end
                
                % Set the TextArea value in the app (assuming app.TextArea is the name of the TextArea)
                textboxx = [textboxx;outputText];
                SpikeExtractiontext = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";
                textboxx = [SpikeText;"";SpikeExtractiontext;"";textboxx;outputText];

                app.TextArea.Value = textboxx;
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeDetectionWindowUIFigure = uifigure('Visible', 'off');
            app.SpikeDetectionWindowUIFigure.Name = 'Spike Detection Window';
            app.SpikeDetectionWindowUIFigure.Icon = 'Logo.png';
            app.Wave_clus3SpikeSortingPanel = uipanel(app.SpikeDetectionWindowUIFigure);
            app.Wave_clus3SpikeSortingPanel.Title = 'Wave_clus 3 Spike Sorting';
            app.CreateNewSpikeSortingButton = uibutton(app.Wave_clus3SpikeSortingPanel, 'push');
            app.CreateNewSpikeSortingButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewSpikeSortingButtonPushed, true);
            app.CreateNewSpikeSortingButton.Text = {'Create New'; 'Spike Sorting'};
            app.ManuallyselectFolderwithSortingResultsButton = uibutton(app.Wave_clus3SpikeSortingPanel, 'push');
            app.ManuallyselectFolderwithSortingResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ManuallyselectFolderwithSortingResultsButtonPushed, true);
            app.ManuallyselectFolderwithSortingResultsButton.Text = {'Manually select'; 'Folder with Sorting'; 'Results'};
            app.ChangeSpikeSortingSettingsButton = uibutton(app.Wave_clus3SpikeSortingPanel, 'push');
            app.ChangeSpikeSortingSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeSpikeSortingSettingsButtonPushed, true);
            app.ChangeSpikeSortingSettingsButton.Text = {'Change Spike '; 'Sorting Settings'};
            app.LoadExistingSpikeSortingButton = uibutton(app.Wave_clus3SpikeSortingPanel, 'push');
            app.LoadExistingSpikeSortingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingSpikeSortingButtonPushed, true);
            app.LoadExistingSpikeSortingButton.Text = {'Load Existing '; 'Spike Sorting'};
            app.SpikeDetectionPanel = uipanel(app.SpikeDetectionWindowUIFigure);
            app.SpikeDetectionPanel.Title = 'Spike Detection';
            app.DetectionMethodDropDownLabel = uilabel(app.SpikeDetectionPanel);
            app.DetectionMethodDropDownLabel.Text = 'Detection Method';
            app.DetectionMethodDropDown = uidropdown(app.SpikeDetectionPanel);
            app.DetectionMethodDropDown.Items = {'Quiroga Method', 'Threshold: Mean - Std', 'Threshold: Median - Std'};
            app.DetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectionMethodDropDownValueChanged, true);
            app.DetectionMethodDropDown.Value = 'Quiroga Method';
            app.ThresholdEditFieldLabel = uilabel(app.SpikeDetectionPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SpikeDetectionPanel, 'text');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = '4';
            app.MeanandStdDropDownLabel = uilabel(app.SpikeDetectionPanel);
            app.MeanandStdDropDownLabel.Text = 'Mean and Std';
            app.MeanDropDown = uidropdown(app.SpikeDetectionPanel);
            app.MeanDropDown.Items = {'All Channel', 'Individual Ch.'};
            app.MeanDropDown.Value = 'Individual Ch.';
            app.VerticalSpikeOffsetToleranceSamplesEditFieldLabel = uilabel(app.SpikeDetectionPanel);
            app.VerticalSpikeOffsetToleranceSamplesEditFieldLabel.Text = {'Vertical Spike Offset'; 'Tolerance [Samples]'};
            app.VerticalSpikeOffsetToleranceSamplesEditField = uieditfield(app.SpikeDetectionPanel, 'text');
            app.VerticalSpikeOffsetToleranceSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalSpikeOffsetToleranceSamplesEditFieldValueChanged, true);
            app.VerticalSpikeOffsetToleranceSamplesEditField.Value = '3';
            app.MinDepthofArtefactmEditFieldLabel = uilabel(app.SpikeDetectionPanel);
            app.MinDepthofArtefactmEditFieldLabel.Text = {'Min. Depth of Artefact'; '[µm]'};
            app.MinDepthofArtefactmEditField = uieditfield(app.SpikeDetectionPanel, 'text');
            app.MinDepthofArtefactmEditField.ValueChangedFcn = createCallbackFcn(app, @MinDepthofArtefactmEditFieldValueChanged, true);
            app.MinDepthofArtefactmEditField.Value = '200';
            app.StartSpikeDetectionButton = uibutton(app.SpikeDetectionPanel, 'push');
            app.StartSpikeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartSpikeDetectionButtonPushed, true);
            app.StartSpikeDetectionButton.Text = {'Start Spike '; 'Detection'};
            app.FilterVerticalSpikeArtefactsCheckBox = uicheckbox(app.SpikeDetectionPanel);
            app.FilterVerticalSpikeArtefactsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterVerticalSpikeArtefactsCheckBoxValueChanged, true);
            app.FilterVerticalSpikeArtefactsCheckBox.Text = {'   Filter Vertical '; '   Spike Artefacts'};
            app.SpikeDetectionWindowLabel = uilabel(app.SpikeDetectionWindowUIFigure);
            app.SpikeDetectionWindowLabel.Text = 'Spike Detection Window';
            app.Image = uiimage(app.SpikeDetectionWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.SpikeDetectionWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.SpikeDetectionWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.SpikeDetectionWindowUIFigure);
            app.InformationLabel = uilabel(app.SpikeDetectionWindowUIFigure);
            app.InformationLabel.Text = 'Information';
            % Show the figure after all components are created
            app.SpikeDetectionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spike_Detection_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpikeDetectionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpikeDetectionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeDetectionWindowUIFigure)
        end
    end
end