classdef Kilosort_Loadfile_Format_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KilosortSaveWindowUIFigure   matlab.ui.Figure
        FolderContentsTextArea       matlab.ui.control.TextArea
        FolderContentsTextAreaLabel  matlab.ui.control.Label
        CloseButton                  matlab.ui.control.Button
        NeuromodToolboxLabel         matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        KilosortSaveWindowLabel      matlab.ui.control.Label
        LoadButton                   matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        EmptyfornonLabel             matlab.ui.control.Label
        AmplitudeScalingFactorEditField  matlab.ui.control.EditField
        AmplitudeScalingFactorEditFieldLabel  matlab.ui.control.Label
        SelectFolderButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        Mainapp=[]; % Description
        Path = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.KilosortSaveWindowUIFigure, 'center');
            app.Mainapp = Mainapp;

            Kilosort4FoldertoSeach = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\kilosort4');
            Kilosort3FoldertoSeach = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\kilosort3');
          
            StringtoShow = [];

            if isfolder(Kilosort4FoldertoSeach) || isfolder(Kilosort3FoldertoSeach)
                [stringArray] = Utility_Extract_Contents_of_Folder(Kilosort4FoldertoSeach);
                StringtoShow = strcat("Path: ", Kilosort4FoldertoSeach);
                app.Path = Kilosort4FoldertoSeach;
            else
                if isfolder(app.Mainapp.Data.Info.Data_Path)
                    [stringArray] = Utility_Extract_Contents_of_Folder(app.Mainapp.Data.Info.Data_Path);
                    StringtoShow = strcat("Path: ", app.Mainapp.Data.Info.Data_Path);
                    app.Path = app.Mainapp.Data.Info.Data_Path;
                else
                    app.FolderContentsTextArea.Value = "Path recording was extracted from was not found or does not exist anymore. Please select a folder manually.";
                    stringArray = [];
                    app.Path = [];
                    return;
                end
            end

            % Filter files with .npy extension
            npyFiles = stringArray(endsWith(stringArray, '.npy'));

            if isempty(npyFiles)
                StringtoShow = [StringtoShow; "";"Warning: No .npy kilosort output files found. Please select a different folder."];
                app.FolderContentsTextArea.Value = StringtoShow;
                return;
            else
                StringtoShow = [StringtoShow; "";"Folder Contents:"; stringArray];
            end

            app.FolderContentsTextArea.Value = StringtoShow;

            pause(0.1);
            
            if isfield(app.Mainapp.Data.Info,'KilosortScalingFactor')
                app.AmplitudeScalingFactorEditField.Value = num2str(app.Mainapp.Data.Info.KilosortScalingFactor);
            else
                ScalingFactorPath32 = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\Scaling Factor int32.mat');
                ScalingFactorPath16 = strcat(app.Mainapp.Data.Info.Data_Path,'\Kilosort\Scaling Factor int16.mat');
                if isfile(ScalingFactorPath32) 
                    load(ScalingFactorPath32,'scalingFactor');
                elseif isfile(ScalingFactorPath16) 
                    load(ScalingFactorPath16,'scalingFactor');
                end

                app.AmplitudeScalingFactorEditField.Value = num2str(scalingFactor);
                app.Mainapp.Data.Info.KilosortScalingFactor = scalingFactor;
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if isempty(app.Path)
                app.FolderContentsTextArea.Value = "No Path selected. Please first select a folder.";
                return;
            end

            if ~isfolder(app.Path)
                app.FolderContentsTextArea.Value = "Selected Path does not exist. Please select an exisitng folder.";
                return;
            end
            
            if isempty(app.AmplitudeScalingFactorEditField.Value)
                KilosortScalingFactor = [];
            else
                KilosortScalingFactor = str2double(app.AmplitudeScalingFactorEditField.Value);
            end

            % Function to load all relevant npy and .mat files Kilosort outputs
            [app.Mainapp.Data] = Spike_Module_Load_Kilosort_Data(app.Mainapp.Data,"No",app.Path,KilosortScalingFactor);


            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            msgbox("Please selecet a folder containing Kilosort Data");

            % Prompt user to select a folder
            folderPath = uigetdir('Select a folder');
            
            % Check if user cancels the dialog
            if folderPath == 0
                disp('Operation canceled by user');
                return;
            end

            StringtoShow = [];

            if isfolder(folderPath)
                [stringArray] = Utility_Extract_Contents_of_Folder(folderPath);
                StringtoShow = strcat("Path: ", folderPath);
                app.Path = folderPath;
            else
                if isfolder(app.Mainapp.Data.Info.Data_Path)
                    [stringArray] = Utility_Extract_Contents_of_Folder(app.Mainapp.Data.Info.Data_Path);
                    StringtoShow = strcat("Path: ", app.Mainapp.Data.Info.Data_Path);
                    app.Path = app.Mainapp.Data.Info.Data_Path;
                else
                    app.FolderContentsTextArea.Value = "Path recording was extracted from was not found or does not exist anymore. Please select a folder.";
                    stringArray = [];
                    app.Path = [];
                    return;
                end
            end

            % Filter files with .npy extension
            npyFiles = stringArray(endsWith(stringArray, '.npy'));

            if isempty(npyFiles)
                StringtoShow = [StringtoShow; "";"Warning: No .npy kilosort output files found. Please select a different folder."];
                return;
            else
                StringtoShow = [StringtoShow; "";"Folder Contents:"; stringArray];
            end

            app.FolderContentsTextArea.Value = StringtoShow;

            pause(0.1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KilosortSaveWindowUIFigure = uifigure('Visible', 'off');
            app.KilosortSaveWindowUIFigure.Name = 'Kilosort Save Window';
            app.KilosortSaveWindowUIFigure.Icon = 'Logo.png';
            app.Panel = uipanel(app.KilosortSaveWindowUIFigure);
            app.SelectFolderButton = uibutton(app.Panel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.AmplitudeScalingFactorEditFieldLabel = uilabel(app.Panel);
            app.AmplitudeScalingFactorEditFieldLabel.Text = {'Amplitude'; 'Scaling Factor'};
            app.AmplitudeScalingFactorEditField = uieditfield(app.Panel, 'text');
            app.EmptyfornonLabel = uilabel(app.Panel);
            app.EmptyfornonLabel.Text = '(Empty for non)';
            app.LoadButton = uibutton(app.KilosortSaveWindowUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.KilosortSaveWindowLabel = uilabel(app.KilosortSaveWindowUIFigure);
            app.KilosortSaveWindowLabel.Text = 'Kilosort Save Window';
            app.Image = uiimage(app.KilosortSaveWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.KilosortSaveWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.KilosortSaveWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.FolderContentsTextAreaLabel = uilabel(app.KilosortSaveWindowUIFigure);
            app.FolderContentsTextAreaLabel.Text = 'Folder Contents';
            app.FolderContentsTextArea = uitextarea(app.KilosortSaveWindowUIFigure);
            % Show the figure after all components are created
            app.KilosortSaveWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kilosort_Loadfile_Format_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.KilosortSaveWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.KilosortSaveWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KilosortSaveWindowUIFigure)
        end
    end
end