classdef Continous_Waveform_Analysis_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UnitAnalysisWindowUIFigure      matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotofWindowMenu        matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        Plot1Panel                      matlab.ui.container.Panel
        UnitstoShow123EditField         matlab.ui.control.EditField
        UnitstoShow123EditFieldLabel    matlab.ui.control.Label
        NumWaveformsEditField           matlab.ui.control.EditField
        NumWaveformsEditFieldLabel      matlab.ui.control.Label
        Plot2Panel                      matlab.ui.container.Panel
        UnitstoShow123EditField_2       matlab.ui.control.EditField
        UnitstoShow123EditField_2Label  matlab.ui.control.Label
        NumWaveformsEditField_2         matlab.ui.control.EditField
        NumWaveformsEditField_2Label    matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        UnitAnalysisWindowLabel         matlab.ui.control.Label
        GeneralSettingsPanel            matlab.ui.container.Panel
        MaxISItoshowsEditField          matlab.ui.control.EditField
        MaxISItoshowsEditFieldLabel     matlab.ui.control.Label
        BinNumberISIEditField           matlab.ui.control.EditField
        BinNumberISIEditFieldLabel      matlab.ui.control.Label
        Plot3Panel                      matlab.ui.container.Panel
        UnitstoShow123EditField_3       matlab.ui.control.EditField
        UnitstoShow123EditField_3Label  matlab.ui.control.Label
        NumWaveformsEditField_3         matlab.ui.control.EditField
        NumWaveformsEditField_3Label    matlab.ui.control.Label
        UnitCurationPanel               matlab.ui.container.Panel
        DeleteUnitDropDown              matlab.ui.control.DropDown
        DeleteUnitDropDownLabel         matlab.ui.control.Label
        DeleteUnitButton                matlab.ui.control.Button
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_1                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        AnalysisWindow = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, AnalysisWindow)
            movegui(app.UnitAnalysisWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            app.AnalysisWindow = AnalysisWindow;
            
            if strcmp(AnalysisWindow,"EventWindow")
                app.UnitAnalysisWindowLabel.Text = "Event Unit Analysis Window";
            else
                app.UnitAnalysisWindowLabel.Text = "Continous Unit Analysis Window";
            end

            for i = 1:length(unique(app.Mainapp.Data.Spikes.SpikeCluster))
                app.DeleteUnitDropDown.Items{i} = convertStringsToChars(strcat("Unit"," ",num2str(i)));
            end

            app.NumWaveformsEditField.Value = "20";
            app.NumWaveformsEditField_2.Value = "20";
            app.NumWaveformsEditField_3.Value = "20";

            app.UnitstoShow123EditField.Value = "1,2";
            app.UnitstoShow123EditField_2.Value = "3,4";
            app.UnitstoShow123EditField_3.Value = "4,5";
            
            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"StartUp",app.AnalysisWindow);

            %% Plot Waveforms
           
            Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs);

            %% Plot ISI
            
            Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
      
            %% Plot Autocorrelogramme

            Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value));
      
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: UnitstoShow123EditField
        function UnitstoShow123EditFieldValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);

            %% Prepare analyiss and plots
            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"U1",app.AnalysisWindow);
            
            %% Plot Waveforms
           
            Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs);

            %% Plot ISI
            
            Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
      
            %% Plot Autocorrelogramme

            Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value));
      
        end

        % Value changed function: NumWaveformsEditField
        function NumWaveformsEditFieldValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);


            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"W1",app.AnalysisWindow);
            
             %% Plot Waveforms
           
            Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs);

        end

        % Value changed function: NumWaveformsEditField_2
        function NumWaveformsEditField_2ValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);


            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"W2",app.AnalysisWindow);
            
             %% Plot Waveforms
           
            Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs);

        end

        % Value changed function: UnitstoShow123EditField_2
        function UnitstoShow123EditField_2ValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);

            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"U2",app.AnalysisWindow);
            
            %% Plot Waveforms
           
            Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs);

            %% Plot ISI
            
            Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
      
            %% Plot Autocorrelogramme

            Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value));
            
        end

        % Value changed function: UnitstoShow123EditField_3
        function UnitstoShow123EditField_3ValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);

            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"U3",app.AnalysisWindow);
            
            %% Plot Waveforms
           
            Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs);

            %% Plot ISI

            Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
      
            %% Plot Autocorrelogramme

            Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value));
            
        end

        % Value changed function: NumWaveformsEditField_3
        function NumWaveformsEditField_3ValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);


            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"W3",app.AnalysisWindow);
            
            %% Plot Waveforms
           
            Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,SpikeWaveforms,SpikeCluster,Waves,Wavefigs);

        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.UnitAnalysisWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.UnitAnalysisWindowUIFigure,fullfile(path,file)) 
        end

        % Value changed function: MaxISItoshowsEditField
        function MaxISItoshowsEditFieldValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);


            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"BinSize",app.AnalysisWindow);

            %% Plot ISIs

            Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
         
        end

        % Value changed function: BinNumberISIEditField
        function BinNumberISIEditFieldValueChanged(app, event)
            %% Check Inputs
            
            [app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value] = Spike_Module_Prepare_WaveForm_Window_and_Analysis_Check_Inputs(app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.BinNumberISIEditField.Value,app.MaxISItoshowsEditField.Value);

            [Units,Waves,Wavefigs,ISIfigs,AutoCfigs,SpikeTimes,SpikePositions,SpikeCluster,SpikeWaveforms,SpikeChannel] = Spike_Module_Prepare_WaveForm_Window_and_Analysis(app.Mainapp.Data,app.UnitstoShow123EditField.Value,app.UnitstoShow123EditField_2.Value,app.UnitstoShow123EditField_3.Value,app.NumWaveformsEditField.Value,app.NumWaveformsEditField_2.Value,app.NumWaveformsEditField_3.Value,app.UIAxes_1,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8,app.UIAxes_9,"ISIMaxTime",app.AnalysisWindow);
            
            %% Plot ISIs

            Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeCluster,SpikeChannel,Units,Waves,ISIfigs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
      
            %% Plot Autocorrelogramme

            Spikes_Module_AutoCorrelogram(app.Mainapp.Data,SpikeTimes,SpikePositions,SpikeChannel,SpikeCluster,AutoCfigs,Units,str2double(app.BinNumberISIEditField.Value));
            
        end

        % Value changing function: NumWaveformsEditField_2
        function NumWaveformsEditField_2ValueChanging(app, event)
           
            
        end

        % Button pushed function: DeleteUnitButton
        function DeleteUnitButtonPushed(app, event)
            [app.Mainapp.Data] = Utility_Delete_Units(app.Mainapp.Data,app.DeleteUnitDropDown.Value);
            
            Placeholder{1} = '';
            app.DeleteUnitDropDown.Items = Placeholder;
            for i = 1:length(unique(app.Mainapp.Data.Spikes.SpikeCluster))
                app.DeleteUnitDropDown.Items{i} = convertStringsToChars(strcat("Unit"," ",num2str(i)));
            end

        end

        % Value changed function: DeleteUnitDropDown
        function DeleteUnitDropDownValueChanged(app, event)
         
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UnitAnalysisWindowUIFigure = uifigure('Visible', 'off');
            app.UnitAnalysisWindowUIFigure.Name = 'Unit Analysis Window';
            app.UnitAnalysisWindowUIFigure.Icon = 'Logo.png';
            app.SnapshotMenu = uimenu(app.UnitAnalysisWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.Menu = uimenu(app.UnitAnalysisWindowUIFigure);
            app.UIAxes_1 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.UnitAnalysisWindowUIFigure);
            zlabel(app.UIAxes_9, 'Z')
            app.UnitCurationPanel = uipanel(app.UnitAnalysisWindowUIFigure);
            app.UnitCurationPanel.Title = 'Unit Curation';
            app.DeleteUnitButton = uibutton(app.UnitCurationPanel, 'push');
            app.DeleteUnitButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteUnitButtonPushed, true);
            app.DeleteUnitButton.Text = 'Delete Unit';
            app.DeleteUnitDropDownLabel = uilabel(app.UnitCurationPanel);
            app.DeleteUnitDropDownLabel.Text = 'Delete Unit';
            app.DeleteUnitDropDown = uidropdown(app.UnitCurationPanel);
            app.DeleteUnitDropDown.Items = {''};
            app.DeleteUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @DeleteUnitDropDownValueChanged, true);
            app.DeleteUnitDropDown.Value = '';
            app.Plot3Panel = uipanel(app.UnitAnalysisWindowUIFigure);
            app.Plot3Panel.Title = 'Plot 3';
            app.NumWaveformsEditField_3Label = uilabel(app.Plot3Panel);
            app.NumWaveformsEditField_3Label.Text = 'Num Waveforms';
            app.NumWaveformsEditField_3 = uieditfield(app.Plot3Panel, 'text');
            app.NumWaveformsEditField_3.ValueChangedFcn = createCallbackFcn(app, @NumWaveformsEditField_3ValueChanged, true);
            app.UnitstoShow123EditField_3Label = uilabel(app.Plot3Panel);
            app.UnitstoShow123EditField_3Label.Text = {'Units to Show'; '(1,2,3)'};
            app.UnitstoShow123EditField_3 = uieditfield(app.Plot3Panel, 'text');
            app.UnitstoShow123EditField_3.ValueChangedFcn = createCallbackFcn(app, @UnitstoShow123EditField_3ValueChanged, true);
            app.GeneralSettingsPanel = uipanel(app.UnitAnalysisWindowUIFigure);
            app.GeneralSettingsPanel.Title = 'General Settings';
            app.BinNumberISIEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.BinNumberISIEditFieldLabel.Text = 'Bin Number ISI';
            app.BinNumberISIEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.BinNumberISIEditField.ValueChangedFcn = createCallbackFcn(app, @BinNumberISIEditFieldValueChanged, true);
            app.BinNumberISIEditField.Value = '150';
            app.MaxISItoshowsEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.MaxISItoshowsEditFieldLabel.Text = {'Max ISI to show'; '[s]'};
            app.MaxISItoshowsEditField = uieditfield(app.GeneralSettingsPanel, 'text');
            app.MaxISItoshowsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxISItoshowsEditFieldValueChanged, true);
            app.MaxISItoshowsEditField.Value = '0.150';
            app.UnitAnalysisWindowLabel = uilabel(app.UnitAnalysisWindowUIFigure);
            app.UnitAnalysisWindowLabel.Text = 'Unit Analysis Window';
            app.Image = uiimage(app.UnitAnalysisWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.UnitAnalysisWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.UnitAnalysisWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Plot2Panel = uipanel(app.UnitAnalysisWindowUIFigure);
            app.Plot2Panel.Title = 'Plot 2';
            app.NumWaveformsEditField_2Label = uilabel(app.Plot2Panel);
            app.NumWaveformsEditField_2Label.Text = 'Num Waveforms';
            app.NumWaveformsEditField_2 = uieditfield(app.Plot2Panel, 'text');
            app.NumWaveformsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumWaveformsEditField_2ValueChanged, true);
            app.NumWaveformsEditField_2.ValueChangingFcn = createCallbackFcn(app, @NumWaveformsEditField_2ValueChanging, true);
            app.UnitstoShow123EditField_2Label = uilabel(app.Plot2Panel);
            app.UnitstoShow123EditField_2Label.Text = {'Units to Show'; '(1,2,3)'};
            app.UnitstoShow123EditField_2 = uieditfield(app.Plot2Panel, 'text');
            app.UnitstoShow123EditField_2.ValueChangedFcn = createCallbackFcn(app, @UnitstoShow123EditField_2ValueChanged, true);
            app.Plot1Panel = uipanel(app.UnitAnalysisWindowUIFigure);
            app.Plot1Panel.Title = 'Plot 1';
            app.NumWaveformsEditFieldLabel = uilabel(app.Plot1Panel);
            app.NumWaveformsEditFieldLabel.Text = 'Num Waveforms';
            app.NumWaveformsEditField = uieditfield(app.Plot1Panel, 'text');
            app.NumWaveformsEditField.ValueChangedFcn = createCallbackFcn(app, @NumWaveformsEditFieldValueChanged, true);
            app.UnitstoShow123EditFieldLabel = uilabel(app.Plot1Panel);
            app.UnitstoShow123EditFieldLabel.Text = {'Units to Show'; '(1,2,3)'};
            app.UnitstoShow123EditField = uieditfield(app.Plot1Panel, 'text');
            app.UnitstoShow123EditField.ValueChangedFcn = createCallbackFcn(app, @UnitstoShow123EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UnitAnalysisWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Continous_Waveform_Analysis_Window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UnitAnalysisWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UnitAnalysisWindowUIFigure)
        end
    end
end