classdef SpikeTrgAvgAskPrepro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeTrgAvgPreproUIFigure  matlab.ui.Figure
        CloseButton                matlab.ui.control.Button
        NeuromodToolboxLabel       matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        PreproSTAWindowLabel       matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        Panel                      matlab.ui.container.Panel
        DownsampleCheckBox         matlab.ui.control.CheckBox
        LowPassFilterCheckBox      matlab.ui.control.CheckBox
        WarningDatawasnotlowdownsampledDonowwithstandardsettingsLabel  matlab.ui.control.Label
        WarningDatawasnotlowpassedfilteredLabel  matlab.ui.control.Label
        ProceedButton              matlab.ui.control.Button
        SaveasnewPreprocessedDatasetDropDown  matlab.ui.control.DropDown
        SaveasnewPreprocessedDatasetDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        DatatoUse = []; % Description
        Downsampled = [];
        LowPass = [];
        SpiketrgAvgPrepro = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DatatoUse, Downsampled, LowPass)
            movegui(app.SpikeTrgAvgPreproUIFigure, 'center');

            app.DatatoUse = DatatoUse; % Description
            app.Downsampled = Downsampled;
            app.LowPass = LowPass;
            
            if strcmp(DatatoUse,'Preprocessed')
                if app.LowPass == 1
                    app.LowPassFilterCheckBox.Enable = "off";
                    app.WarningDatawasnotlowpassedfilteredLabel.Text = "Data is already low pass filtered";
                else
                    app.LowPassFilterCheckBox.Enable = "on";
                end
            else
                app.LowPassFilterCheckBox.Enable = "on";
            end

            if Downsampled == 1
                app.DownsampleCheckBox.Enable = "off";
                app.WarningDatawasnotlowdownsampledDonowwithstandardsettingsLabel.Text = "Data is already downsampled";
            else
                app.DownsampleCheckBox.Enable = "on";
            end
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)

            if app.LowPassFilterCheckBox.Value == 1
                app.SpiketrgAvgPrepro.LowPass = 1;
            else
                app.SpiketrgAvgPrepro.LowPass = 0;
            end

            if app.DownsampleCheckBox.Value == 1
                app.SpiketrgAvgPrepro.Downsample = 1;
            else
                app.SpiketrgAvgPrepro.Downsample = 0;
            end

            app.SpiketrgAvgPrepro.SavePreproData = app.SaveasnewPreprocessedDatasetDropDown.Value;
           
            % Resume execution to return the output
            uiresume(app.SpikeTrgAvgPreproUIFigure);
           
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeTrgAvgPreproUIFigure = uifigure('Visible', 'off');
            app.SpikeTrgAvgPreproUIFigure.Name = 'Spike Trg Avg Prepro';
            app.SpikeTrgAvgPreproUIFigure.Icon = 'Logo.png';
            app.Panel = uipanel(app.SpikeTrgAvgPreproUIFigure);
            app.SaveasnewPreprocessedDatasetDropDownLabel = uilabel(app.Panel);
            app.SaveasnewPreprocessedDatasetDropDownLabel.Text = {'Save as new '; 'Preprocessed Dataset'};
            app.SaveasnewPreprocessedDatasetDropDown = uidropdown(app.Panel);
            app.SaveasnewPreprocessedDatasetDropDown.Items = {'No', 'Yes'};
            app.SaveasnewPreprocessedDatasetDropDown.Value = 'No';
            app.ProceedButton = uibutton(app.Panel, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.WarningDatawasnotlowpassedfilteredLabel = uilabel(app.Panel);
            app.WarningDatawasnotlowpassedfilteredLabel.Text = {'Warning: Data was not low passed filtered!'; 'Do now with standard settings?'};
            app.WarningDatawasnotlowdownsampledDonowwithstandardsettingsLabel = uilabel(app.Panel);
            app.WarningDatawasnotlowdownsampledDonowwithstandardsettingsLabel.Text = {'Warning: Data was not low downsampled!'; 'Do now with standard settings?'};
            app.LowPassFilterCheckBox = uicheckbox(app.Panel);
            app.LowPassFilterCheckBox.Text = '  Low Pass Filter';
            app.DownsampleCheckBox = uicheckbox(app.Panel);
            app.DownsampleCheckBox.Text = '    Downsample';
            app.TextArea = uitextarea(app.SpikeTrgAvgPreproUIFigure);
            app.TextArea.Value = {'Warning:'; ''; 'Data to compute spike triggered average with is not low pass filtered and/or downsampled.'; 'Select low pass filter to compute spike triggered LFP, otherwise spikes data will also be part of computation!'; 'Not downsampling can take very long and requires a lot of memory depending on the number of spikes.'};
            app.PreproSTAWindowLabel = uilabel(app.SpikeTrgAvgPreproUIFigure);
            app.PreproSTAWindowLabel.Text = 'Prepro STA Window';
            app.Image = uiimage(app.SpikeTrgAvgPreproUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.SpikeTrgAvgPreproUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.SpikeTrgAvgPreproUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.SpikeTrgAvgPreproUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeTrgAvgAskPrepro(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpikeTrgAvgPreproUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpikeTrgAvgPreproUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeTrgAvgPreproUIFigure)
        end
    end
end