classdef Autorun_Manager_Select_Template < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Autorun_Manager_Select_TemplateUIFigure  matlab.ui.Figure
        ProceedButton                matlab.ui.control.Button
        SelectTemplateDropDown       matlab.ui.control.DropDown
        SelectTemplateDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        AutorunApp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, AutorunApp)
            movegui(app.Autorun_Manager_Select_TemplateUIFigure, 'center');

            app.AutorunApp = AutorunApp;
            
            PathToSearch = strcat(app.AutorunApp.Mainapp.executableFolder,'\Autorun_Configs\Default(do not edit!)');

            [stringArray] = Utility_Extract_Contents_of_Folder(PathToSearch);

            for i = 1:length(stringArray)
                app.SelectTemplateDropDown.Items{i}= convertStringsToChars(stringArray(i));
            end
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            app.AutorunApp.SelectedTemplate = app.SelectTemplateDropDown.Value;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Autorun_Manager_Select_TemplateUIFigure = uifigure('Visible', 'off');
            app.Autorun_Manager_Select_TemplateUIFigure.Name = 'Autorun_Manager_Select_Template';
            app.Autorun_Manager_Select_TemplateUIFigure.Icon = 'Logo.png';
            app.SelectTemplateDropDownLabel = uilabel(app.Autorun_Manager_Select_TemplateUIFigure);
            app.SelectTemplateDropDownLabel.Text = 'Select Template';
            app.SelectTemplateDropDown = uidropdown(app.Autorun_Manager_Select_TemplateUIFigure);
            app.ProceedButton = uibutton(app.Autorun_Manager_Select_TemplateUIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            % Show the figure after all components are created
            app.Autorun_Manager_Select_TemplateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Autorun_Manager_Select_Template(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Autorun_Manager_Select_TemplateUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.Autorun_Manager_Select_TemplateUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Autorun_Manager_Select_TemplateUIFigure)
        end
    end
end