classdef ERP_Analysis_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventRelatedAnalysisUIFigure   matlab.ui.Figure
        SnapshotMenu                   matlab.ui.container.Menu
        SaveSnapshotofWindowMenu       matlab.ui.container.Menu
        PNGMenu                        matlab.ui.container.Menu
        JPGMenu                        matlab.ui.container.Menu
        SVGMenu                        matlab.ui.container.Menu
        PlotMenu                       matlab.ui.container.Menu
        ChangePlotPropertiesMenu       matlab.ui.container.Menu
        SingleERPPlotMenu              matlab.ui.container.Menu
        FontSizeMenu                   matlab.ui.container.Menu
        LineWidthEventsMenu_3          matlab.ui.container.Menu
        LineWidthMeanMenu              matlab.ui.container.Menu
        LineWidthTriggerMenu           matlab.ui.container.Menu
        ColorEventsMenu_3              matlab.ui.container.Menu
        ColorMeanMenu                  matlab.ui.container.Menu
        ColorTriggerMenu               matlab.ui.container.Menu
        BackgorundColorMenu            matlab.ui.container.Menu
        XLabelMenu                     matlab.ui.container.Menu
        YLabelMenu                     matlab.ui.container.Menu
        RESETMenu                      matlab.ui.container.Menu
        MultipleERPPlotMenu_2          matlab.ui.container.Menu
        FontSizeMenu_3                 matlab.ui.container.Menu
        LineWidthMeanMenu_2            matlab.ui.container.Menu
        LineWidthTriggerMenu_2         matlab.ui.container.Menu
        ColorTriggerMenu_2             matlab.ui.container.Menu
        BackgorundColorMenu_2          matlab.ui.container.Menu
        XLabelMenu_3                   matlab.ui.container.Menu
        YLabelMenu_3                   matlab.ui.container.Menu
        RESETMenu_3                    matlab.ui.container.Menu
        SaveCostumeAppearanceMenu      matlab.ui.container.Menu
        DeleteCostumeAppearanceMenu    matlab.ui.container.Menu
        ERPSingleChannelPlotTypeMenu   matlab.ui.container.Menu
        LinesMenu                      matlab.ui.container.Menu
        ImagescMenu                    matlab.ui.container.Menu
        ExportDataMenu                 matlab.ui.container.Menu
        ERPoverEventsMenu              matlab.ui.container.Menu
        matMenu                        matlab.ui.container.Menu
        txtMenu                        matlab.ui.container.Menu
        xlsxMenu                       matlab.ui.container.Menu
        ERPoverChannelMenu             matlab.ui.container.Menu
        matMenu_2                      matlab.ui.container.Menu
        txtMenu_2                      matlab.ui.container.Menu
        xlsxMenu_2                     matlab.ui.container.Menu
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        ERPAnalysisSettingsPanel       matlab.ui.container.Panel
        BaselineNormalizeCheckBox      matlab.ui.control.CheckBox
        EventNumberSelectionEditField_3  matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel_3  matlab.ui.control.Label
        TriggerNumberSelectionMatlabExpressionsLabel_2  matlab.ui.control.Label
        EventNumberSelectionEditField_2  matlab.ui.control.EditField
        Slider                         matlab.ui.control.Slider
        ChannelSelectionDropDown       matlab.ui.control.EditField
        ChannelLabel                   matlab.ui.control.Label
        DataToExtractFromDropDown      matlab.ui.control.DropDown
        DataToExtractFromLabel         matlab.ui.control.Label
        EventChannelSelectionDropDown  matlab.ui.control.DropDown
        EventChannelSelectionLabel     matlab.ui.control.Label
        DataLineSpacingLabel           matlab.ui.control.Label
        ERPAnalysisSettingsLabel       matlab.ui.control.Label
        ChannelSelectionDropDown_2     matlab.ui.control.DropDown
        ChannelSelectionLabel          matlab.ui.control.Label
        DataTypeDropDown               matlab.ui.control.DropDown
        EventDataTypeDropDownLabel     matlab.ui.control.Label
        EventNumberSelectionEditField  matlab.ui.control.EditField
        TriggerNumberSelectionMatlabExpressionsLabel  matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu4                   matlab.ui.container.ContextMenu
        ChangeSpacingLimitsMenu        matlab.ui.container.Menu
    end

    
    properties (Access = public)
        EventRelatedWindow = []; % Description
        EventTime = [];
        TimearoundEvent = [];
        colorMap = [];
        Mainapp = [];
        
        TrialIdentities = [];

        SingleChannelPlotType = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EventRelatedWindow, Mainapp)
            movegui(app.EventRelatedAnalysisUIFigure, 'center');

            app.EventRelatedWindow = EventRelatedWindow;
            app.Mainapp = Mainapp;
            
            app = Utility_Change_Light_Dark_Mode(app,"EventERP");

            if ~isfield(app.Mainapp.Data,'Events')
                msgbox("No events extracted. Please extract events first!. Exiting.");
                delete(app);
                return;
            end
            
            %% -------------------- Set UP Window General things-------------------- 
            app.Slider.Limits =  app.Mainapp.Slider.Limits;
            app.Slider.Value =  app.Mainapp.Slider.Value;
            app.colorMap =  app.Mainapp.tempcolorMapset;

            if isfield(app.Mainapp.Data,'Preprocessed')
                app.DataToExtractFromDropDown.Items{1} = 'Preprocessed Data';
                app.DataToExtractFromDropDown.Items{2} = 'Raw Data';
            else
                app.DataToExtractFromDropDown.Items{1} = 'Raw Data';
            end
            
            for i = 1:length(app.Mainapp.Data.Info.EventChannelNames)
                app.EventChannelSelectionDropDown.Items{i} = convertStringsToChars(app.Mainapp.Data.Info.EventChannelNames{i});
            end

            %% -------------------- Check if prepro event related data found and can be offered -------------------- 
            [texttoshow,Value] = Event_Module_Check_Event_Preprocessing(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,"Raw Event Related Data");
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.Items = texttoshow;
            app.DataTypeDropDown.Value = Value;

            %% -------------------- Set up time -------------------- 
            spaceindicie = strfind(app.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));
            app.TimearoundEvent(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));

            %% -------------------- Set UP Channel Selection -------------------- 

            AllChannels = {};
            if app.Mainapp.Data.Info.ProbeInfo.SwitchTopBottomChannel == 1
                TempActiveChannel = (str2double(app.Mainapp.Data.Info.ProbeInfo.NrChannel)*str2double(app.Mainapp.Data.Info.ProbeInfo.NrRows)+1)-sort(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel);
                for i = 1:size(app.Mainapp.Data.Raw,1)  
                    AllChannels{i} = num2str(TempActiveChannel(i));
                end
            else
                for i = 1:size(app.Mainapp.Data.Raw,1)
                    AllChannels{i} = num2str(app.Mainapp.Data.Info.ProbeInfo.ActiveChannel(i));
                end
            end

            app.ChannelSelectionDropDown_2.Items = AllChannels;


            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataTypeDropDown.Value);
            
            %% -------------------- Set Basline Normlaization and trigger nhumber -------------------- 
            EventRange = str2double(strsplit(app.Mainapp.Data.Info.EventRelatedDataTimeRange,' '));
            app.EventNumberSelectionEditField_3.Value = strcat('-',num2str(EventRange(1)),',0');
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.EventRelatedData,2)),' Trigger');
            else
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)),' Trigger');
            end

            %% -------------------- Populate event channel selection -------------------- 
            [app.EventNumberSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventNumberSelectionEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,1);
            
            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));

            %% -------------------- PLOT -------------------- 

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.Mainapp.EventLFPERP = [];
    
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event ERP Window')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
            catch
                
            end
            
            delete(app)
        end

        % Value changed function: EventNumberSelectionEditField
        function EventNumberSelectionEditFieldValueChanged(app, event)
            %% Check if user inputs are correct
            
            [app.EventNumberSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventNumberSelectionEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,0);
            
            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));

            if length(unique(EventNr)) ~= length(EventNr)
                msgbox("Format Error: Some trials are present more than ones!")
                app.EventNumberSelectionEditField.Value = event.PreviousValue;
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Value changed function: ChannelSelectionDropDown
        function ChannelSelectionDropDownValueChanged(app, event)
      
EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'MultipleERPOnly',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'MultipleERPOnly',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end

        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataTypeDropDown.Value);
            
            %% -------------------- Set Basline Normlaization and trigger nhumber -------------------- 
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.EventRelatedData,2)),' Trigger');
            else
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)),' Trigger');
            end

            %% -------------------- Populate event channel selection -------------------- 
            [app.EventNumberSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventNumberSelectionEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,1);
            
            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));

            %% -------------------- PLOT -------------------- 
                        
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.EventRelatedAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.EventRelatedAnalysisUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: ERPoverEventsMenu
        function ERPoverEventsMenuSelected(app, event)
            
        end

        % Menu selected function: ERPoverChannelMenu
        function ERPoverChannelMenuSelected(app, event)
            
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat", app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Events",0);
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt", app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Events",0);
        end

        % Menu selected function: matMenu_2
        function matMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".mat", app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Channel",0);
        end

        % Menu selected function: txtMenu_2
        function txtMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".txt", app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Channel",0);
        end

        % Menu selected function: ChangeSpacingLimitsMenu
        function ChangeSpacingLimitsMenuSelected(app, event)
            Channel_Spacing_Selection(app);
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize', app.Mainapp,"SingleERPWindowDataFontSize");
            waitfor(h);

            app.UIAxes.FontSize =  app.Mainapp.PlotAppearance.ERPWindow.SingleERP.FontSize;
            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'xlabel', app.Mainapp,"SingleERPWindowXLabel");
            waitfor(h);
            
            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel', app.Mainapp,"SingleERPWindowYLabel");
            waitfor(h);

            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: FontSizeMenu_3
        function FontSizeMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'fontsize', app.Mainapp,"MultipleERPWindowDataFontSize");
            waitfor(h);

            app.UIAxes_2.FontSize =  app.Mainapp.PlotAppearance.ERPWindow.MultipleERP.FontSize;
            
            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));

            %% Plot ERP
            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: LineWidthMeanMenu_2
        function LineWidthMeanMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'linewidth', app.Mainapp,"MultipleERPLineWidthMean");
            waitfor(h);

            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: LineWidthTriggerMenu_2
        function LineWidthTriggerMenu_2Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'linewidth', app.Mainapp,"MultipleERPLineWidthTrigger");
            waitfor(h);

            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: ColorTriggerMenu_2
        function ColorTriggerMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                 app.Mainapp.PlotAppearance.ERPWindow.MultipleERP.TriggerColor = selectedColor;
            end

            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

            
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: BackgorundColorMenu_2
        function BackgorundColorMenu_2Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                 app.Mainapp.PlotAppearance.ERPWindow.MultipleERP.BackgroundColor = selectedColor;
                app.UIAxes_2.Color =  app.Mainapp.PlotAppearance.ERPWindow.MultipleERP.BackgroundColor;
            end
        end

        % Menu selected function: XLabelMenu_3
        function XLabelMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'xlabel', app.Mainapp,"MultipleERPWindowXLabel");
            waitfor(h);

            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: YLabelMenu_3
        function YLabelMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes_2,'ylabel', app.Mainapp,"MultipleERPWindowYLabel");
            waitfor(h);

            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: LineWidthEventsMenu_3
        function LineWidthEventsMenu_3Selected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth', app.Mainapp,"SingleERPLineWidthEvents");
            waitfor(h);

            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: LineWidthMeanMenu
        function LineWidthMeanMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth', app.Mainapp,"SingleERPLineWidthMean");
            waitfor(h);

            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: LineWidthTriggerMenu
        function LineWidthTriggerMenuSelected(app, event)
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth', app.Mainapp,"SingleERPLineWidthTrigger");
            waitfor(h);

            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

             if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: ColorEventsMenu_3
        function ColorEventsMenu_3Selected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                 app.Mainapp.PlotAppearance.ERPWindow.SingleERP.EventColor = selectedColor;
            end


            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: ColorMeanMenu
        function ColorMeanMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                 app.Mainapp.PlotAppearance.ERPWindow.SingleERP.MeanColor = selectedColor;
            end

            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: ColorTriggerMenu
        function ColorTriggerMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                 app.Mainapp.PlotAppearance.ERPWindow.SingleERP.TriggerColor = selectedColor;
            end


            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                 app.Mainapp.PlotAppearance.ERPWindow.SingleERP.BackgroundColor = selectedColor;
                app.UIAxes.Color =  app.Mainapp.PlotAppearance.ERPWindow.SingleERP.BackgroundColor;
                app.UIAxes_2.Color =  app.Mainapp.PlotAppearance.ERPWindow.MultipleERP.BackgroundColor;
                app.UIAxes_2.FontSize =  app.Mainapp.PlotAppearance.ERPWindow.MultipleERP.FontSize;
            end
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 

            [ app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("ERPPlot", app.Mainapp.PlotAppearance);

            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: RESETMenu_3
        function RESETMenu_3Selected(app, event)
            app.UIAxes_2.NextPlot = "replace"; 
            plot(app.UIAxes_2,0,0);
            app.UIAxes_2.NextPlot = "add"; 

            [ app.Mainapp.PlotAppearance] = Organize_Set_Standard_PlotAppearance("ERPPlot", app.Mainapp.PlotAppearance);

            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: SaveCostumeAppearanceMenu
        function SaveCostumeAppearanceMenuSelected(app, event)
             app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance( app.Mainapp.PlotAppearance, app.Mainapp.executableFolder,"Save");
        end

        % Menu selected function: DeleteCostumeAppearanceMenu
        function DeleteCostumeAppearanceMenuSelected(app, event)
             app.Mainapp.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance( app.Mainapp.PlotAppearance, app.Mainapp.executableFolder,"Delete");
        end

        % Close request function: EventRelatedAnalysisUIFigure
        function EventRelatedAnalysisUIFigureCloseRequest(app, event)
            try
                app.Mainapp.EventLFPERP = [];
    
                if ~isempty(app.Mainapp.ProbeViewWindowHandle) % delete option from probe view 
                    for i = 1:length(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items)
                        if strcmp(app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items{i},'Event ERP Window')
                            app.Mainapp.ProbeViewWindowHandle.ChangeforWindowDropDown.Items(i) = [];
                            break;
                        end
                    end
                else % if no probe view available
     
                end
            catch
                
            end
            
            delete(app)
            
        end

        % Value changed function: ChannelSelectionDropDown_2
        function ChannelSelectionDropDown_2ValueChanged(app, event)
            %% Check if user inputs are correct
            
            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
             
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
            
        end

        % Value changed function: EventChannelSelectionDropDown
        function EventChannelSelectionDropDownValueChanged(app, event)
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataTypeDropDown.Value);
            
            %% -------------------- Set Basline Normlaization and trigger nhumber -------------------- 
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.EventRelatedData,2)),' Trigger');
            else
                app.EventNumberSelectionEditField_2.Value = strcat(num2str(size(app.Mainapp.Data.PreprocessedEventRelatedData,2)),' Trigger');
            end

            %% -------------------- Check if prepro event related data found and can be offered -------------------- 
            [texttoshow,Value] = Event_Module_Check_Event_Preprocessing(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.Items = texttoshow;
            app.DataTypeDropDown.Value = Value;
            %% -------------------- Populate event channel selection -------------------- 
            [app.EventNumberSelectionEditField.Value] = Event_Module_Check_EventInput(app.EventNumberSelectionEditField.Value,app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.DataTypeDropDown.Value,1);
            
            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));

            %% -------------------- PLOT -------------------- 
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Value changed function: DataToExtractFromDropDown
        function DataToExtractFromDropDownValueChanged(app, event)
            
            %% -------------------- Extract Event Related Data -------------------- 
            [app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataTypeDropDown.Value);
            
            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));

            %% -------------------- PLOT -------------------- 
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            %% Check if user inputs are correct
            
            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
             
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
            
        end

        % Context menu opening function: ContextMenu4
        function ContextMenu4Opening(app, event)
            
        end

        % Menu selected function: LinesMenu
        function LinesMenuSelected(app, event)
            cla(app.UIAxes);
            reset(app.UIAxes);         % Resets axis properties to default

            app.SingleChannelPlotType = 0;

            %% Check if user inputs are correct
            
            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
             
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: ImagescMenu
        function ImagescMenuSelected(app, event)
            cla(app.UIAxes);
            reset(app.UIAxes);         % Resets axis properties to default

            app.SingleChannelPlotType = 1;

            %% Check if user inputs are correct
            
            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));
             
            %% Plot ERP

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Menu selected function: SVGMenu
        function SVGMenuSelected(app, event)
            [file, path] = uiputfile('*.svg', 'Save as');
            
            exportgraphics(app.EventRelatedAnalysisUIFigure, fullfile(path,file), 'ContentType', 'vector');
        end

        % Menu selected function: xlsxMenu
        function xlsxMenuSelected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx", app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Events",0);
        end

        % Menu selected function: xlsxMenu_2
        function xlsxMenu_2Selected(app, event)
            TimeRangePlot = app.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.Mainapp.CurrentPlotData,app.Mainapp.Data,".xlsx", app.Mainapp.executableFolder,TimeRangePlot,StartTime,"Event Related Potential over Channel",0);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes,"ERPSingleChannel");
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            Utility_Open_UIAxes_as_Figure(app.Mainapp.Data,app.UIAxes_2,"ERPMultipleChannel");
        end

        % Value changed function: BaselineNormalizeCheckBox
        function BaselineNormalizeCheckBoxValueChanged(app, event)
            value = app.BaselineNormalizeCheckBox.Value;
            %% -------------------- Extract Event Related Data -------------------- 
            %[app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataTypeDropDown.Value);
            
            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));

            %% -------------------- PLOT -------------------- 
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end

        % Value changed function: EventNumberSelectionEditField_3
        function EventNumberSelectionEditField_3ValueChanged(app, event)
            value = app.EventNumberSelectionEditField_3.Value;
            %% -------------------- Extract Event Related Data -------------------- 
            %[app.Mainapp.Data,~] = Event_Module_Extract_Event_Related_Data(app.Mainapp.Data,app.EventChannelSelectionDropDown.Value,app.TimearoundEvent,app.DataToExtractFromDropDown.Value,app.DataTypeDropDown.Value);
            
            EventNr = sort(eval(app.EventNumberSelectionEditField.Value));

            %% -------------------- PLOT -------------------- 
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.EventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            else
                [~,~,~,~,app.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.Mainapp.Data,app.UIAxes,app.UIAxes_2,app.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr,:),app.Mainapp.Data.Info.EventRelatedTime, app.Mainapp.ActiveChannel,[],app.colorMap,app.Slider.Value,'All',[],app.Mainapp.CurrentPlotData, app.Mainapp.PlotAppearance,app.ChannelSelectionDropDown_2.Value,app.DataTypeDropDown.Value,app.SingleChannelPlotType,EventNr,app.Mainapp.PreservePlotChannelLocations,app.BaselineNormalizeCheckBox.Value,app.EventNumberSelectionEditField_3.Value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventRelatedAnalysisUIFigure = uifigure('Visible', 'off');
            app.EventRelatedAnalysisUIFigure.Name = 'Event Related Analysis';
            app.EventRelatedAnalysisUIFigure.Icon = 'Logo.png';
            app.EventRelatedAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @EventRelatedAnalysisUIFigureCloseRequest, true);
            app.SnapshotMenu = uimenu(app.EventRelatedAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.SVGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.SVGMenu.MenuSelectedFcn = createCallbackFcn(app, @SVGMenuSelected, true);
            app.SVGMenu.Text = 'SVG';
            app.PlotMenu = uimenu(app.EventRelatedAnalysisUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.ChangePlotPropertiesMenu = uimenu(app.PlotMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.SingleERPPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SingleERPPlotMenu.Text = 'Single ERP Plot';
            app.FontSizeMenu = uimenu(app.SingleERPPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthEventsMenu_3 = uimenu(app.SingleERPPlotMenu);
            app.LineWidthEventsMenu_3.MenuSelectedFcn = createCallbackFcn(app, @LineWidthEventsMenu_3Selected, true);
            app.LineWidthEventsMenu_3.Text = 'LineWidth Events';
            app.LineWidthMeanMenu = uimenu(app.SingleERPPlotMenu);
            app.LineWidthMeanMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthMeanMenuSelected, true);
            app.LineWidthMeanMenu.Text = 'LineWidth Mean';
            app.LineWidthTriggerMenu = uimenu(app.SingleERPPlotMenu);
            app.LineWidthTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTriggerMenuSelected, true);
            app.LineWidthTriggerMenu.Text = 'LineWidth Trigger';
            app.ColorEventsMenu_3 = uimenu(app.SingleERPPlotMenu);
            app.ColorEventsMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ColorEventsMenu_3Selected, true);
            app.ColorEventsMenu_3.Text = 'Color Events';
            app.ColorMeanMenu = uimenu(app.SingleERPPlotMenu);
            app.ColorMeanMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorMeanMenuSelected, true);
            app.ColorMeanMenu.Text = 'Color Mean';
            app.ColorTriggerMenu = uimenu(app.SingleERPPlotMenu);
            app.ColorTriggerMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorTriggerMenuSelected, true);
            app.ColorTriggerMenu.Text = 'Color Trigger';
            app.BackgorundColorMenu = uimenu(app.SingleERPPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.SingleERPPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.SingleERPPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.SingleERPPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.MultipleERPPlotMenu_2 = uimenu(app.ChangePlotPropertiesMenu);
            app.MultipleERPPlotMenu_2.Text = 'Multiple ERP Plot';
            app.FontSizeMenu_3 = uimenu(app.MultipleERPPlotMenu_2);
            app.FontSizeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_3Selected, true);
            app.FontSizeMenu_3.Text = 'FontSize';
            app.LineWidthMeanMenu_2 = uimenu(app.MultipleERPPlotMenu_2);
            app.LineWidthMeanMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LineWidthMeanMenu_2Selected, true);
            app.LineWidthMeanMenu_2.Text = 'LineWidth Mean';
            app.LineWidthTriggerMenu_2 = uimenu(app.MultipleERPPlotMenu_2);
            app.LineWidthTriggerMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTriggerMenu_2Selected, true);
            app.LineWidthTriggerMenu_2.Text = 'LineWidth Trigger';
            app.ColorTriggerMenu_2 = uimenu(app.MultipleERPPlotMenu_2);
            app.ColorTriggerMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ColorTriggerMenu_2Selected, true);
            app.ColorTriggerMenu_2.Text = 'Color Trigger';
            app.BackgorundColorMenu_2 = uimenu(app.MultipleERPPlotMenu_2);
            app.BackgorundColorMenu_2.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenu_2Selected, true);
            app.BackgorundColorMenu_2.Text = 'Backgorund Color';
            app.XLabelMenu_3 = uimenu(app.MultipleERPPlotMenu_2);
            app.XLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenu_3Selected, true);
            app.XLabelMenu_3.Text = 'XLabel';
            app.YLabelMenu_3 = uimenu(app.MultipleERPPlotMenu_2);
            app.YLabelMenu_3.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenu_3Selected, true);
            app.YLabelMenu_3.Text = 'YLabel';
            app.RESETMenu_3 = uimenu(app.MultipleERPPlotMenu_2);
            app.RESETMenu_3.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_3Selected, true);
            app.RESETMenu_3.Text = 'RESET';
            app.SaveCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCostumeAppearanceMenuSelected, true);
            app.SaveCostumeAppearanceMenu.Text = 'Save Costume Appearance';
            app.DeleteCostumeAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCostumeAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCostumeAppearanceMenuSelected, true);
            app.DeleteCostumeAppearanceMenu.Text = 'Delete Costume Appearance';
            app.ERPSingleChannelPlotTypeMenu = uimenu(app.PlotMenu);
            app.ERPSingleChannelPlotTypeMenu.Text = 'ERP Single Channel Plot Type';
            app.LinesMenu = uimenu(app.ERPSingleChannelPlotTypeMenu);
            app.LinesMenu.MenuSelectedFcn = createCallbackFcn(app, @LinesMenuSelected, true);
            app.LinesMenu.Text = 'Lines';
            app.ImagescMenu = uimenu(app.ERPSingleChannelPlotTypeMenu);
            app.ImagescMenu.MenuSelectedFcn = createCallbackFcn(app, @ImagescMenuSelected, true);
            app.ImagescMenu.Text = 'Imagesc';
            app.ExportDataMenu = uimenu(app.EventRelatedAnalysisUIFigure);
            app.ExportDataMenu.Text = '   Export Data   ';
            app.ERPoverEventsMenu = uimenu(app.ExportDataMenu);
            app.ERPoverEventsMenu.MenuSelectedFcn = createCallbackFcn(app, @ERPoverEventsMenuSelected, true);
            app.ERPoverEventsMenu.Text = 'ERP over Events';
            app.matMenu = uimenu(app.ERPoverEventsMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ERPoverEventsMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.xlsxMenu = uimenu(app.ERPoverEventsMenu);
            app.xlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenuSelected, true);
            app.xlsxMenu.Text = '.xlsx';
            app.ERPoverChannelMenu = uimenu(app.ExportDataMenu);
            app.ERPoverChannelMenu.MenuSelectedFcn = createCallbackFcn(app, @ERPoverChannelMenuSelected, true);
            app.ERPoverChannelMenu.Text = 'ERP over Channel';
            app.matMenu_2 = uimenu(app.ERPoverChannelMenu);
            app.matMenu_2.MenuSelectedFcn = createCallbackFcn(app, @matMenu_2Selected, true);
            app.matMenu_2.Text = '.mat';
            app.txtMenu_2 = uimenu(app.ERPoverChannelMenu);
            app.txtMenu_2.MenuSelectedFcn = createCallbackFcn(app, @txtMenu_2Selected, true);
            app.txtMenu_2.Text = '.txt';
            app.xlsxMenu_2 = uimenu(app.ERPoverChannelMenu);
            app.xlsxMenu_2.MenuSelectedFcn = createCallbackFcn(app, @xlsxMenu_2Selected, true);
            app.xlsxMenu_2.Text = '.xlsx';
            app.UIAxes = uiaxes(app.EventRelatedAnalysisUIFigure);
            app.UIAxes_2 = uiaxes(app.EventRelatedAnalysisUIFigure);
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.ERPAnalysisSettingsPanel = uipanel(app.EventRelatedAnalysisUIFigure);
            app.ERPAnalysisSettingsPanel.Title = 'ERP Analysis Settings';
            app.TriggerNumberSelectionMatlabExpressionsLabel = uilabel(app.ERPAnalysisSettingsPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel.Text = {'Trial Selection'; '(Matlab Expressions)'};
            app.EventNumberSelectionEditField = uieditfield(app.ERPAnalysisSettingsPanel, 'text');
            app.EventNumberSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @EventNumberSelectionEditFieldValueChanged, true);
            app.EventDataTypeDropDownLabel = uilabel(app.ERPAnalysisSettingsPanel);
            app.EventDataTypeDropDownLabel.Text = 'Event Data Type';
            app.DataTypeDropDown = uidropdown(app.ERPAnalysisSettingsPanel);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = {};
            app.ChannelSelectionLabel = uilabel(app.ERPAnalysisSettingsPanel);
            app.ChannelSelectionLabel.Text = 'Channel Selection';
            app.ChannelSelectionDropDown_2 = uidropdown(app.ERPAnalysisSettingsPanel);
            app.ChannelSelectionDropDown_2.Items = {};
            app.ChannelSelectionDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDown_2ValueChanged, true);
            app.ChannelSelectionDropDown_2.Value = {};
            app.ERPAnalysisSettingsLabel = uilabel(app.ERPAnalysisSettingsPanel);
            app.ERPAnalysisSettingsLabel.Text = 'ERP Analysis Settings';
            app.DataLineSpacingLabel = uilabel(app.ERPAnalysisSettingsPanel);
            app.DataLineSpacingLabel.Text = {'Data Line'; 'Spacing'};
            app.EventChannelSelectionLabel = uilabel(app.ERPAnalysisSettingsPanel);
            app.EventChannelSelectionLabel.Text = 'Event Channel Selection';
            app.EventChannelSelectionDropDown = uidropdown(app.ERPAnalysisSettingsPanel);
            app.EventChannelSelectionDropDown.Items = {};
            app.EventChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @EventChannelSelectionDropDownValueChanged, true);
            app.EventChannelSelectionDropDown.Value = {};
            app.DataToExtractFromLabel = uilabel(app.ERPAnalysisSettingsPanel);
            app.DataToExtractFromLabel.Text = 'Data To Extract From';
            app.DataToExtractFromDropDown = uidropdown(app.ERPAnalysisSettingsPanel);
            app.DataToExtractFromDropDown.Items = {};
            app.DataToExtractFromDropDown.ValueChangedFcn = createCallbackFcn(app, @DataToExtractFromDropDownValueChanged, true);
            app.DataToExtractFromDropDown.Value = {};
            app.ChannelLabel = uilabel(app.ERPAnalysisSettingsPanel);
            app.ChannelLabel.Enable = 'off';
            app.ChannelLabel.Visible = 'off';
            app.ChannelLabel.Text = {'Channel Selection'; '[from,to]'};
            app.ChannelSelectionDropDown = uieditfield(app.ERPAnalysisSettingsPanel, 'text');
            app.ChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDownValueChanged, true);
            app.ChannelSelectionDropDown.Editable = 'off';
            app.ChannelSelectionDropDown.Enable = 'off';
            app.ChannelSelectionDropDown.Visible = 'off';
            app.Slider = uislider(app.ERPAnalysisSettingsPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.EventNumberSelectionEditField_2 = uieditfield(app.ERPAnalysisSettingsPanel, 'text');
            app.TriggerNumberSelectionMatlabExpressionsLabel_2 = uilabel(app.ERPAnalysisSettingsPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel_2.Text = 'Total Nr Of Trigger';
            app.TriggerNumberSelectionMatlabExpressionsLabel_3 = uilabel(app.ERPAnalysisSettingsPanel);
            app.TriggerNumberSelectionMatlabExpressionsLabel_3.Text = {'Baseline Window'; '[from, to] in s'};
            app.EventNumberSelectionEditField_3 = uieditfield(app.ERPAnalysisSettingsPanel, 'text');
            app.EventNumberSelectionEditField_3.ValueChangedFcn = createCallbackFcn(app, @EventNumberSelectionEditField_3ValueChanged, true);
            app.BaselineNormalizeCheckBox = uicheckbox(app.ERPAnalysisSettingsPanel);
            app.BaselineNormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineNormalizeCheckBoxValueChanged, true);
            app.BaselineNormalizeCheckBox.Text = {'   Baseline '; '   Normalize'};
            app.CloseButton = uibutton(app.EventRelatedAnalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Button = uibutton(app.EventRelatedAnalysisUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Open_Figure_Icon.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.EventRelatedAnalysisUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'Open_Figure_Icon.png';
            app.Button_2.Text = '';
            app.ContextMenu4 = uicontextmenu(app.EventRelatedAnalysisUIFigure);
            app.ContextMenu4.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu4Opening, true);
            app.ChangeSpacingLimitsMenu = uimenu(app.ContextMenu4);
            app.ChangeSpacingLimitsMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeSpacingLimitsMenuSelected, true);
            app.ChangeSpacingLimitsMenu.Text = 'Change Spacing Limits';
            % Assign app.ContextMenu4
            app.Slider.ContextMenu = app.ContextMenu4;
            % Show the figure after all components are created
            app.EventRelatedAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ERP_Analysis_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EventRelatedAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.EventRelatedAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventRelatedAnalysisUIFigure)
        end
    end
end